classdef Jia_Test < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        NeuroPhysTestingUIFigure      matlab.ui.Figure
        TabGroup                      matlab.ui.container.TabGroup
        MEPDeterminationTab           matlab.ui.container.Tab
        UnlinkLabChartButton          matlab.ui.control.Button
        toLabel                       matlab.ui.control.Label
        fromLabel                     matlab.ui.control.Label
        StartingPageNoEditField       matlab.ui.control.NumericEditField
        StartingPageNoEditFieldLabel  matlab.ui.control.Label
        ChannelNoEditField            matlab.ui.control.NumericEditField
        ChannelNoEditFieldLabel       matlab.ui.control.Label
        MEPtimerangemsEditField       matlab.ui.control.NumericEditField
        MEPtimerangemsEditFieldLabel  matlab.ui.control.Label
        ScopeViewtimerangemsEditField_6  matlab.ui.control.NumericEditField
        BaselinetimerangemsEditField  matlab.ui.control.NumericEditField
        BaselinetimerangemsEditFieldLabel  matlab.ui.control.Label
        ScopeViewtimerangemsEditField_4  matlab.ui.control.NumericEditField
        ScopeViewtimerangemsEditField_3  matlab.ui.control.NumericEditField
        ScopeViewtimerangemsEditField_2  matlab.ui.control.NumericEditField
        ScopeViewtimerangemsEditField_2Label  matlab.ui.control.Label
        STARTButton                   matlab.ui.control.Button
        Lamp                          matlab.ui.control.Lamp
        Switch                        matlab.ui.control.Switch
        TMSRecruitmentCurveTab        matlab.ui.container.Tab
        NewAMTCheckBox                matlab.ui.control.CheckBox
        AMTMSOEditField_28            matlab.ui.control.NumericEditField
        EditField_18                  matlab.ui.control.EditField
        AMTMSOEditField_27            matlab.ui.control.NumericEditField
        CheckBox_18                   matlab.ui.control.CheckBox
        EditField_17                  matlab.ui.control.EditField
        AMTMSOEditField_26            matlab.ui.control.NumericEditField
        CheckBox_17                   matlab.ui.control.CheckBox
        EditField_16                  matlab.ui.control.EditField
        EditField_15                  matlab.ui.control.EditField
        EditField_14                  matlab.ui.control.EditField
        EditField_13                  matlab.ui.control.EditField
        EditField_12                  matlab.ui.control.EditField
        EditField_11                  matlab.ui.control.EditField
        EditField_10                  matlab.ui.control.EditField
        EditField_9                   matlab.ui.control.EditField
        EditField_1                   matlab.ui.control.EditField
        EditField_8                   matlab.ui.control.EditField
        EditField_7                   matlab.ui.control.EditField
        EditField_6                   matlab.ui.control.EditField
        EditField_5                   matlab.ui.control.EditField
        EditField_4                   matlab.ui.control.EditField
        EditField_3                   matlab.ui.control.EditField
        EditField_2                   matlab.ui.control.EditField
        AMTMSOEditField_25            matlab.ui.control.NumericEditField
        AMTMSOEditField_24            matlab.ui.control.NumericEditField
        AMTMSOEditField_23            matlab.ui.control.NumericEditField
        AMTMSOEditField_22            matlab.ui.control.NumericEditField
        AMTMSOEditField_21            matlab.ui.control.NumericEditField
        AMTMSOEditField_20            matlab.ui.control.NumericEditField
        AMTMSOEditField_19            matlab.ui.control.NumericEditField
        AMTMSOEditField_18            matlab.ui.control.NumericEditField
        AMTMSOEditField_9             matlab.ui.control.NumericEditField
        AMTMSOEditField_8             matlab.ui.control.NumericEditField
        AMTMSOEditField_7             matlab.ui.control.NumericEditField
        AMTMSOEditField_6             matlab.ui.control.NumericEditField
        AMTMSOEditField_5             matlab.ui.control.NumericEditField
        AMTMSOEditField_4             matlab.ui.control.NumericEditField
        AMTMSOEditField_3             matlab.ui.control.NumericEditField
        AMTMSOEditField_2             matlab.ui.control.NumericEditField
        PageLabel_2                   matlab.ui.control.Label
        MSOLabel_2                    matlab.ui.control.Label
        AMTLabel_3                    matlab.ui.control.Label
        PageLabel                     matlab.ui.control.Label
        CheckBox_16                   matlab.ui.control.CheckBox
        CheckBox_15                   matlab.ui.control.CheckBox
        CheckBox_14                   matlab.ui.control.CheckBox
        CheckBox_13                   matlab.ui.control.CheckBox
        CheckBox_12                   matlab.ui.control.CheckBox
        CheckBox_11                   matlab.ui.control.CheckBox
        CheckBox_10                   matlab.ui.control.CheckBox
        CheckBox_9                    matlab.ui.control.CheckBox
        MSOLabel                      matlab.ui.control.Label
        AMTLabel_2                    matlab.ui.control.Label
        CheckBox_8                    matlab.ui.control.CheckBox
        CheckBox_7                    matlab.ui.control.CheckBox
        CheckBox_6                    matlab.ui.control.CheckBox
        CheckBox_5                    matlab.ui.control.CheckBox
        CheckBox_4                    matlab.ui.control.CheckBox
        CheckBox_3                    matlab.ui.control.CheckBox
        CheckBox_2                    matlab.ui.control.CheckBox
        CheckBox                      matlab.ui.control.CheckBox
        AMTMSOEditField               matlab.ui.control.NumericEditField
        AMTMSOEditFieldLabel          matlab.ui.control.Label
        PlotRCButton                  matlab.ui.control.StateButton
        HReflexRecruitmentCurveTab    matlab.ui.container.Tab
        CheckBox_142                  matlab.ui.control.CheckBox
        CheckBox_141                  matlab.ui.control.CheckBox
        CheckBox_140                  matlab.ui.control.CheckBox
        CheckBox_139                  matlab.ui.control.CheckBox
        EditField_58                  matlab.ui.control.EditField
        EditField_57                  matlab.ui.control.EditField
        EditField_56                  matlab.ui.control.EditField
        EditField_55                  matlab.ui.control.EditField
        EditField_54                  matlab.ui.control.EditField
        EditField_53                  matlab.ui.control.EditField
        EditField_52                  matlab.ui.control.EditField
        EditField_51                  matlab.ui.control.EditField
        EditField_50                  matlab.ui.control.EditField
        EditField_49                  matlab.ui.control.EditField
        EditField_48                  matlab.ui.control.EditField
        EditField_47                  matlab.ui.control.EditField
        EditField_46                  matlab.ui.control.EditField
        EditField_45                  matlab.ui.control.EditField
        EditField_44                  matlab.ui.control.EditField
        EditField_43                  matlab.ui.control.EditField
        EditField_42                  matlab.ui.control.EditField
        EditField_41                  matlab.ui.control.EditField
        EditField_40                  matlab.ui.control.EditField
        EditField_39                  matlab.ui.control.EditField
        EditField_38                  matlab.ui.control.EditField
        EditField_37                  matlab.ui.control.EditField
        EditField_36                  matlab.ui.control.EditField
        EditField_35                  matlab.ui.control.EditField
        EditField_34                  matlab.ui.control.EditField
        EditField_33                  matlab.ui.control.EditField
        EditField_32                  matlab.ui.control.EditField
        EditField_31                  matlab.ui.control.EditField
        EditField_30                  matlab.ui.control.EditField
        EditField_29                  matlab.ui.control.EditField
        EditField_28                  matlab.ui.control.EditField
        EditField_27                  matlab.ui.control.EditField
        EditField_26                  matlab.ui.control.EditField
        EditField_25                  matlab.ui.control.EditField
        EditField_24                  matlab.ui.control.EditField
        EditField_23                  matlab.ui.control.EditField
        EditField_22                  matlab.ui.control.EditField
        EditField_21                  matlab.ui.control.EditField
        EditField_20                  matlab.ui.control.EditField
        EditField_19                  matlab.ui.control.EditField
        AMTMSOEditField_188           matlab.ui.control.NumericEditField
        AMTMSOEditField_187           matlab.ui.control.NumericEditField
        AMTMSOEditField_186           matlab.ui.control.NumericEditField
        AMTMSOEditField_185           matlab.ui.control.NumericEditField
        AMTMSOEditField_184           matlab.ui.control.NumericEditField
        AMTMSOEditField_183           matlab.ui.control.NumericEditField
        AMTMSOEditField_182           matlab.ui.control.NumericEditField
        AMTMSOEditField_181           matlab.ui.control.NumericEditField
        AMTMSOEditField_180           matlab.ui.control.NumericEditField
        AMTMSOEditField_179           matlab.ui.control.NumericEditField
        AMTMSOEditField_178           matlab.ui.control.NumericEditField
        AMTMSOEditField_177           matlab.ui.control.NumericEditField
        AMTMSOEditField_176           matlab.ui.control.NumericEditField
        AMTMSOEditField_175           matlab.ui.control.NumericEditField
        AMTMSOEditField_174           matlab.ui.control.NumericEditField
        AMTMSOEditField_173           matlab.ui.control.NumericEditField
        AMTMSOEditField_172           matlab.ui.control.NumericEditField
        AMTMSOEditField_171           matlab.ui.control.NumericEditField
        AMTMSOEditField_170           matlab.ui.control.NumericEditField
        AMTMSOEditField_169           matlab.ui.control.NumericEditField
        AMTMSOEditField_168           matlab.ui.control.NumericEditField
        AMTMSOEditField_167           matlab.ui.control.NumericEditField
        AMTMSOEditField_166           matlab.ui.control.NumericEditField
        AMTMSOEditField_165           matlab.ui.control.NumericEditField
        AMTMSOEditField_164           matlab.ui.control.NumericEditField
        AMTMSOEditField_163           matlab.ui.control.NumericEditField
        AMTMSOEditField_162           matlab.ui.control.NumericEditField
        AMTMSOEditField_161           matlab.ui.control.NumericEditField
        AMTMSOEditField_160           matlab.ui.control.NumericEditField
        AMTMSOEditField_159           matlab.ui.control.NumericEditField
        AMTMSOEditField_158           matlab.ui.control.NumericEditField
        AMTMSOEditField_157           matlab.ui.control.NumericEditField
        AMTMSOEditField_156           matlab.ui.control.NumericEditField
        AMTMSOEditField_155           matlab.ui.control.NumericEditField
        AMTMSOEditField_154           matlab.ui.control.NumericEditField
        AMTMSOEditField_153           matlab.ui.control.NumericEditField
        AMTMSOEditField_152           matlab.ui.control.NumericEditField
        AMTMSOEditField_151           matlab.ui.control.NumericEditField
        AMTMSOEditField_150           matlab.ui.control.NumericEditField
        AMTMSOEditField_149           matlab.ui.control.NumericEditField
        CheckBox_138                  matlab.ui.control.CheckBox
        CheckBox_137                  matlab.ui.control.CheckBox
        CheckBox_136                  matlab.ui.control.CheckBox
        CheckBox_135                  matlab.ui.control.CheckBox
        CheckBox_134                  matlab.ui.control.CheckBox
        CheckBox_133                  matlab.ui.control.CheckBox
        CheckBox_132                  matlab.ui.control.CheckBox
        CheckBox_131                  matlab.ui.control.CheckBox
        CheckBox_130                  matlab.ui.control.CheckBox
        CheckBox_129                  matlab.ui.control.CheckBox
        CheckBox_128                  matlab.ui.control.CheckBox
        CheckBox_127                  matlab.ui.control.CheckBox
        CheckBox_126                  matlab.ui.control.CheckBox
        CheckBox_125                  matlab.ui.control.CheckBox
        CheckBox_124                  matlab.ui.control.CheckBox
        CheckBox_123                  matlab.ui.control.CheckBox
        CheckBox_122                  matlab.ui.control.CheckBox
        CheckBox_121                  matlab.ui.control.CheckBox
        CheckBox_120                  matlab.ui.control.CheckBox
        CheckBox_119                  matlab.ui.control.CheckBox
        CheckBox_118                  matlab.ui.control.CheckBox
        CheckBox_117                  matlab.ui.control.CheckBox
        CheckBox_116                  matlab.ui.control.CheckBox
        CheckBox_115                  matlab.ui.control.CheckBox
        CheckBox_114                  matlab.ui.control.CheckBox
        CheckBox_113                  matlab.ui.control.CheckBox
        CheckBox_112                  matlab.ui.control.CheckBox
        CheckBox_111                  matlab.ui.control.CheckBox
        CheckBox_110                  matlab.ui.control.CheckBox
        CheckBox_109                  matlab.ui.control.CheckBox
        CheckBox_108                  matlab.ui.control.CheckBox
        CheckBox_107                  matlab.ui.control.CheckBox
        CheckBox_106                  matlab.ui.control.CheckBox
        CheckBox_105                  matlab.ui.control.CheckBox
        CheckBox_104                  matlab.ui.control.CheckBox
        CheckBox_103                  matlab.ui.control.CheckBox
        CheckBox_102                  matlab.ui.control.CheckBox
        CheckBox_101                  matlab.ui.control.CheckBox
        CheckBox_100                  matlab.ui.control.CheckBox
        CheckBox_99                   matlab.ui.control.CheckBox
        PageLabel_14                  matlab.ui.control.Label
        mALabel_12                    matlab.ui.control.Label
        PageLabel_13                  matlab.ui.control.Label
        mALabel_11                    matlab.ui.control.Label
        PageLabel_12                  matlab.ui.control.Label
        mALabel_10                    matlab.ui.control.Label
        PageLabel_11                  matlab.ui.control.Label
        mALabel_9                     matlab.ui.control.Label
        toLabel_2                     matlab.ui.control.Label
        fromLabel_2                   matlab.ui.control.Label
        HmsEditField                  matlab.ui.control.NumericEditField
        HmsEditFieldLabel             matlab.ui.control.Label
        MmsEditField                  matlab.ui.control.NumericEditField
        MmsEditFieldLabel             matlab.ui.control.Label
        HmstoEditField                matlab.ui.control.NumericEditField
        MmstoEditField                matlab.ui.control.NumericEditField
        PlotHMRCButton                matlab.ui.control.StateButton
        SubpagebyEditField            matlab.ui.control.NumericEditField
        SubpagebyEditFieldLabel       matlab.ui.control.Label
        UIAxes                        matlab.ui.control.UIAxes
    end

    
    properties (Access = public)
        gLCApp % labchart server
        gLCDoc % current labchart doc
        gChans %channel number
        gChansData % retrieved data
        gPageData % retrieved data
        
        thresh;% AMT(or RMT) threshold i.e., 0.1 or 0.05
        MEPdiff; % peak to peak amplitude during [astartms,aendms]
        AmtRmt=[];% store a log with meps presence
        
        time_prestim; % time before stimulation in seconds
        time_poststim; % time after stimulation in seconds
        bstartms; % baseline start in ms before stimulation
        bendms; % baseline end in ms before stimulation
        astartms; % mep start in ms after stimulation
        aendms; % mep end in ms after stimulation
        timevec;
        
        PageNumber;
        gCommentTime; % 'Trigger time'
        gNewSampleTime; % each newsample has a timestamp
        gLatestBlock; % number of blocks before the ongoing one
        gBlockSecsPerTick; % 1/freq
        
        data_plot; % data for plotting
        data_base; % data for baseline based on [bstartms,bendms]
        data_mep; % data for mep based on [astartms,aendms]
        
        
        color_enable;
        color_disable
        
        
        mstartms;
        mendms;
        hstartms;
        hendms;
        subpage;
        
    end
    
    methods (Access = public)
        
        function OnBlockStart(varargin)
            global gLCDoc1
            global gLatestBlock;
            global gBlockSecsPerTick;
            gLatestBlock = gLCDoc1.NumberOfRecords;
            gBlockSecsPerTick = gLCDoc1.GetRecordSecsPerTick(gLatestBlock);
            
        end
        
        function OnNewSamples(varargin)
            global gLCDoc1
            global gchans;
            global gBlockSecsPerTick;
            
            global gcommentTime;
            global time_prestim;
            global time_poststim;
            global bstartms
            global bendms;
            global astartms
            global aendms;
            
            global MEPdiff
            global pageNumber;
            
            global ax
            global thresh
            global lamp
            global h
            global obj
            
            % get new samples
            if ~isempty(gcommentTime)
                current=clock;
                afterCommentTime=current-gcommentTime;
                afterCommentTime_s=afterCommentTime(4)*3600+afterCommentTime(5)*60+afterCommentTime(6);
                
                if afterCommentTime_s<time_poststim/1000+0.5 && afterCommentTime_s>time_poststim/1000+0.25
                    
                    if isempty(h)
                        
                       
                        data_plot=gLCDoc1.GetScopeChannelData(1, gchans, pageNumber, 1, -time_prestim, time_poststim);
                        %pageData(:,pageNumber)=data_plot
                        
                        base_start_ind=(-time_prestim+bstartms)/1000*1/gBlockSecsPerTick+1;
                        base_end_ind=(-time_prestim+bendms)/1000*1/gBlockSecsPerTick;
                        
                        mep_start_ind=(-time_prestim+astartms)/1000*1/gBlockSecsPerTick+1+1;
                        mep_end_ind=(-time_prestim+aendms)/1000*1/gBlockSecsPerTick+1;
                        
                        data_base=data_plot(base_start_ind:base_end_ind);
                        data_mep=data_plot(mep_start_ind:mep_end_ind);

                        if thresh==0.1
                            MEPdiff=max(data_mep)-min(data_mep)-max(data_base)+min(data_base);
                        elseif thresh==0.05
                            MEPdiff=max(data_mep)-min(data_base);
                        end
                        
                        timevec=linspace(time_prestim,time_poststim,length(data_plot)+1);
                        
                        plot(ax,timevec(1:end-1),data_plot,'linewidth',2,'color','blue');
                        hold(ax,'on');
                        plot(ax,[bstartms,bstartms],get(ax,'ylim'),':g','linewidth',3);hold(ax,'on');
                        plot(ax,[bendms,bendms],get(ax,'ylim'),':g','linewidth',3);
                        hold(ax,'on');
                        plot(ax,[astartms,astartms],get(ax,'ylim'),'-g','linewidth',3);hold(ax,'on');
                        plot(ax,[aendms,aendms],get(ax,'ylim'),'-g','linewidth',3);
                        hold(ax,'on');
                        plot(ax,get(ax,'xlim'),[0,0],':k');
                        ylabel(ax,'mV');
                        xlabel(ax,'time (ms)');
                        
                        hold(ax,'on');
                        t=text(ax,0.8, 0.9, num2str(round(MEPdiff*1000)/1000), 'Units', 'Normalized', 'fontsize', 40);
                        
                        if MEPdiff>thresh
                            set(t, 'Color','red')
                            lamp.Color = 'red';                            
                            Speak(obj, 'Yes')
                        else
                            set(t, 'Color','k')
                            lamp.Color = [0.96,0.96,0.96];
                            Speak(obj, 'No')
                        end
                        title(ax,['Stimulation Page ', num2str(pageNumber)],'fontsize',30);
                        
                        
                    end
                end
            end
            
        end
        
        
        function OnCommentAdded(varargin)
            global gcommentTime;
            global pageNumber;
            global ax;
            global h;
           
            gcommentTime=clock;
            pageNumber=pageNumber+1;
            cla(ax);
            h=[];
            
        end
    end
    
    methods (Access = private)
        
        function [MSO_MEP_all,MSO_MEP_mean,AMT_mean] = RC_prep(app,pages,mso,amt, ...
                ind_mep,MSO_MEP_all,MSO_MEP_mean,AMT_mean)
        
        MEP=[];
        MSO=[];
        
        for i =1:length(pages)
            data_current(:,i)=app.gLCDoc.GetScopeChannelData(1, app.gChans, pages(i), 1, -app.time_prestim/1000, app.time_poststim/1000);
            MEP(i,1)=max(data_current(ind_mep,i))-min(data_current(ind_mep,i));
            MSO(i,1)=mso;
            
        end
        
        MSO_MEP_all=[MSO_MEP_all;MSO,MEP];
        MSO_MEP_mean=[MSO_MEP_mean;unique(MSO),mean(MEP)];
        AMT_mean=[AMT_mean;string(['[',char(amt),']'])];
        
        end
        
        function [mA_M_H_all,mA_M_H_mean] = Hreflex_RC_prep(app,page,subpage,mA, ...
                ind_m,ind_h,mA_M_H_all,mA_M_H_mean)
        subpage=1;
        
        for sub=1:subpage
            data_current=app.gLCDoc.GetScopeChannelData(1, app.gChans, page, sub, -app.time_prestim/1000, app.time_poststim/1000);
            
            p2p_M(sub)=range(data_current(ind_m));
            p2p_H(sub)=range(data_current(ind_h));
            mA_M_H_all=[mA_M_H_all;mA,p2p_M(sub),p2p_H(sub)];
        end
        mA_M_H_mean=[mA_M_H_mean;mA,mean(p2p_M),mean(p2p_H)];
        
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            global gLCDoc1
            
            global gLatestBlock;
            global gBlockSecsPerTick;
            global gchans;
            
            global time_prestim;
            global time_poststim;
            global bstartms
            global bendms;
            global astartms
            global aendms;
            global gcommentTime;
            
            global MEPdiff
            global thresh
            global pageNumber;
            
            global ax;
            global h;
            global lamp;
            global obj;
            
            ax=app.UIAxes;
            lamp=app.Lamp;
            
            screenSize = get(groot,'ScreenSize');
            screenWidth = screenSize(3);
            screenHeight = screenSize(4);
            
            
            %% MEP Determination Tab
            app.ScopeViewtimerangemsEditField_2.Value=-150;
            app.ScopeViewtimerangemsEditField_3.Value=100;
            app.BaselinetimerangemsEditField.Value=-150;
            app.ScopeViewtimerangemsEditField_4.Value=-5;
            app.MEPtimerangemsEditField.Value=5;
            app.ScopeViewtimerangemsEditField_6.Value=45;
            app.ChannelNoEditField.Value=2;
            app.StartingPageNoEditField.Value=1;
            
            
            app.EditField_1.FontSize=9;
            app.EditField_2.FontSize=9;
            app.EditField_3.FontSize=9;
            app.EditField_4.FontSize=9;
            app.EditField_5.FontSize=9;
            app.EditField_6.FontSize=9;
            app.EditField_7.FontSize=9;
            app.EditField_8.FontSize=9;
            app.EditField_9.FontSize=9;
            app.EditField_10.FontSize=9;
            app.EditField_11.FontSize=9;
            app.EditField_12.FontSize=9;
            app.EditField_13.FontSize=9;
            app.EditField_14.FontSize=9;
            app.EditField_15.FontSize=9;
            app.EditField_16.FontSize=9;
            app.EditField_17.FontSize=9;
            app.EditField_18.FontSize=9;
            
            app.AMTMSOEditField_28.Visible='Off';
            app.CheckBox_17.Visible='Off';
            app.CheckBox_18.Visible='Off';
            app.AMTMSOEditField_26.Visible='Off';
            app.AMTMSOEditField_27.Visible='Off';
            app.EditField_17.Visible='Off';
            app.EditField_18.Visible='Off';
            app.color_enable='green';
            app.color_disable=[0.96,0.96,0.96];
            
            
            app.MmsEditField.Value=5;
            app.MmstoEditField.Value=10;
            app.HmsEditField.Value=15;
            app.HmstoEditField.Value=40;
            app.SubpagebyEditField.Value=10;
            
            NET.addAssembly('System.Speech');
            obj = System.Speech.Synthesis.SpeechSynthesizer;
            obj.Volume = 100;
            
        end

        % Button pushed function: STARTButton
        function STARTButtonPushed(app, event)
            
            global gLCDoc1
            
            global gLatestBlock;
            global gBlockSecsPerTick;
            global gchans;
            
            global time_prestim;
            global time_poststim;
            global bstartms
            global bendms;
            global astartms
            global aendms;
            global gcommentTime;
            
            global MEPdiff
            global thresh
            global pageNumber;
            
            global ax;
            global h;
            global lamp;
            
            gLatestBlock=[]; % number of blocks before the ongoing one
            gBlockSecsPerTick=[]; % 1/freq
            MEPdiff=[]; % peak to peak amplitude during [astartms,aendms] substracted from baseline p2p
            
            
            if contains(app.Switch.Value,'AMT')
                thresh = 0.1;
            else
                thresh = 0.05;
            end
            
            time_prestim=app.ScopeViewtimerangemsEditField_2.Value; % time before stimulation in seconds
            time_poststim=app.ScopeViewtimerangemsEditField_3.Value;% time after stimulation in seconds
            bstartms=app.BaselinetimerangemsEditField.Value;% baseline end in ms before stimulation
            bendms=app.ScopeViewtimerangemsEditField_4.Value;% mep start in ms after stimulation
            astartms=app.MEPtimerangemsEditField.Value;% mep start in ms after stimulation
            aendms=app.ScopeViewtimerangemsEditField_6.Value;% mep end in ms after stimulation
            gchans=app.ChannelNoEditField.Value;%Channel Number
            
            % check pagenumber in labchart
            
            
            h=[];
            gcommentTime=[];
            
            %% get the active LabChart
            try
                app.gLCApp = actxGetRunningServer('ADIChart.Application');
            catch err
                error('Please start LabChart before running this script.');
            end
            if (isempty(app.gLCApp.ActiveDocument))
                error('Please open a document in LabChart before running this script.');
            end
            gLCDoc1 = app.gLCApp.ActiveDocument;
            
           
            pageNumber=app.StartingPageNoEditField.Value-1;%mep starting page
            
            %% register events
            gLCDoc1.registerevent({
                'OnStartSamplingBlock' @app.OnBlockStart;
                'OnNewSamples' @app.OnNewSamples;
                'OnCommentAdded' @app.OnCommentAdded;
                })
            
            disp(gLCDoc1.eventlisteners)
        end

        % Value changed function: PlotRCButton
        function PlotRCButtonValueChanged(app, event)
            
            %% get the active LabChart
            try
                app.gLCApp = actxGetRunningServer('ADIChart.Application');
            catch err
                error('Please start LabChart before running this script.');
            end
            if (isempty(app.gLCApp.ActiveDocument))
                error('Please open a document in LabChart before running this script.');
            end
            app.gLCDoc = app.gLCApp.ActiveDocument;
            
            app.time_prestim=app.ScopeViewtimerangemsEditField_2.Value; % time before stimulation in seconds
            app.time_poststim=app.ScopeViewtimerangemsEditField_3.Value;% time after stimulation in seconds
            app.astartms=app.MEPtimerangemsEditField.Value;% mep start in ms after stimulation
            app.aendms=app.ScopeViewtimerangemsEditField_6.Value;% mep end in ms after stimulation
            app.gChans=app.ChannelNoEditField.Value;%Channel Number
            
            %data_example=app.gLCDoc.GetScopeChannelData(1, app.gChans, 1, 1, -app.time_prestim/1000, app.time_poststim/1000)
            %app.timevec=linspace(app.time_prestim,app.time_poststim,length(data_example)+1)
            
            
            app.gLatestBlock = app.gLCDoc.NumberOfRecords;
            app.gBlockSecsPerTick = app.gLCDoc.GetRecordSecsPerTick(app.gLatestBlock-1);
            
            mep_start_ind=(-app.time_prestim+app.astartms)/1000*1/app.gBlockSecsPerTick+1+1;
            mep_end_ind=(-app.time_prestim+app.aendms)/1000*1/app.gBlockSecsPerTick+1;
            
            ind_mep=[mep_start_ind:mep_end_ind];
            
            
            MSO_MEP_all=[];
            MSO_MEP_mean=[];
            AMT_mean=[];
            
            %% obtain the page data
            if app.CheckBox.Value==1
                text_current=app.EditField_1.Value;
                if ~isempty(text_current) && ~strcmp(text_current,'NaN')
                    pages=str2double(strsplit(app.EditField_1.Value,','));
                    mso=app.AMTMSOEditField_2.Value;
                    amt=string(app.CheckBox.Text);
                    [MSO_MEP_all,MSO_MEP_mean,AMT_mean] = RC_prep(app,pages,mso,amt, ...
                        ind_mep,MSO_MEP_all,MSO_MEP_mean,AMT_mean);
                end
            end
            if app.CheckBox_2.Value==1
                text_current=app.EditField_2.Value;
                if ~isempty(text_current) && ~strcmp(text_current,'NaN')
                    pages=str2double(strsplit(app.EditField_2.Value,','));
                    mso=app.AMTMSOEditField_3.Value;
                    amt=string(app.CheckBox_2.Text);
                    [MSO_MEP_all,MSO_MEP_mean,AMT_mean] = RC_prep(app,pages,mso,amt, ...
                        ind_mep,MSO_MEP_all,MSO_MEP_mean,AMT_mean);
                end
            end
            if app.CheckBox_3.Value==1
                text_current=app.EditField_3.Value;
                if ~isempty(text_current) && ~strcmp(text_current,'NaN')
                    pages=str2double(strsplit(app.EditField_3.Value,','));
                    mso=app.AMTMSOEditField_4.Value;
                    amt=string(app.CheckBox_3.Text);
                    [MSO_MEP_all,MSO_MEP_mean,AMT_mean] = RC_prep(app,pages,mso,amt, ...
                        ind_mep,MSO_MEP_all,MSO_MEP_mean,AMT_mean);
                end
            end
            if app.CheckBox_4.Value==1
                text_current=app.EditField_4.Value;
                if ~isempty(text_current) && ~strcmp(text_current,'NaN')
                    pages=str2double(strsplit(app.EditField_4.Value,','));
                    mso=app.AMTMSOEditField_5.Value;
                    amt=string(app.CheckBox_4.Text);
                    [MSO_MEP_all,MSO_MEP_mean,AMT_mean] = RC_prep(app,pages,mso,amt, ...
                        ind_mep,MSO_MEP_all,MSO_MEP_mean,AMT_mean);
                end
            end
            if app.CheckBox_5.Value==1
                text_current=app.EditField_5.Value;
                if ~isempty(text_current) && ~strcmp(text_current,'NaN')
                    pages=str2double(strsplit(app.EditField_5.Value,','));
                    
                    mso=app.AMTMSOEditField_6.Value;
                    amt=string(app.CheckBox_5.Text);
                    [MSO_MEP_all,MSO_MEP_mean,AMT_mean] = RC_prep(app,pages,mso,amt, ...
                        ind_mep,MSO_MEP_all,MSO_MEP_mean,AMT_mean);
                end
            end
            if app.CheckBox_6.Value==1
                text_current=app.EditField_6.Value;
                if ~isempty(text_current) && ~strcmp(text_current,'NaN')
                    pages=str2double(strsplit(app.EditField_6.Value,','));
                    mso=app.AMTMSOEditField_7.Value;
                    amt=string(app.CheckBox_6.Text);
                    [MSO_MEP_all,MSO_MEP_mean,AMT_mean] = RC_prep(app,pages,mso,amt, ...
                        ind_mep,MSO_MEP_all,MSO_MEP_mean,AMT_mean);
                end
            end
            if app.CheckBox_7.Value==1
                text_current=app.EditField_7.Value;
                if ~isempty(text_current) && ~strcmp(text_current,'NaN')
                    pages=str2double(strsplit(app.EditField_7.Value,','));
                    mso=app.AMTMSOEditField_8.Value;
                    amt=string(app.CheckBox_7.Text);
                    [MSO_MEP_all,MSO_MEP_mean,AMT_mean] = RC_prep(app,pages,mso,amt, ...
                        ind_mep,MSO_MEP_all,MSO_MEP_mean,AMT_mean);
                end
            end
            if app.CheckBox_8.Value==1
                text_current=app.EditField_8.Value;
                if ~isempty(text_current) && ~strcmp(text_current,'NaN')
                    pages=str2double(strsplit(app.EditField_8.Value,','));
                    mso=app.AMTMSOEditField_9.Value;
                    amt=string(app.CheckBox_8.Text);
                    [MSO_MEP_all,MSO_MEP_mean,AMT_mean] = RC_prep(app,pages,mso,amt, ...
                        ind_mep,MSO_MEP_all,MSO_MEP_mean,AMT_mean);
                end
            end
            if app.CheckBox_9.Value==1
                text_current=app.EditField_9.Value;
                if ~isempty(text_current) && ~strcmp(text_current,'NaN')
                    pages=str2double(strsplit(app.EditField_9.Value,','));
                    mso=app.AMTMSOEditField_18.Value;
                    amt=string(app.CheckBox_9.Text);
                    [MSO_MEP_all,MSO_MEP_mean,AMT_mean] = RC_prep(app,pages,mso,amt, ...
                        ind_mep,MSO_MEP_all,MSO_MEP_mean,AMT_mean);
                end
            end
            if app.CheckBox_10.Value==1
                text_current=app.EditField_10.Value;
                if ~isempty(text_current) && ~strcmp(text_current,'NaN')
                    pages=str2double(strsplit(app.EditField_10.Value,','));
                    mso=app.AMTMSOEditField_19.Value;
                    amt=string(app.CheckBox_10.Text);
                    [MSO_MEP_all,MSO_MEP_mean,AMT_mean] = RC_prep(app,pages,mso,amt, ...
                        ind_mep,MSO_MEP_all,MSO_MEP_mean,AMT_mean);
                end
            end
            if app.CheckBox_11.Value==1
                text_current=app.EditField_11.Value;
                if ~isempty(text_current) && ~strcmp(text_current,'NaN')
                    pages=str2double(strsplit(app.EditField_11.Value,','));
                    mso=app.AMTMSOEditField_20.Value;
                    amt=string(app.CheckBox_11.Text);
                    [MSO_MEP_all,MSO_MEP_mean,AMT_mean] = RC_prep(app,pages,mso,amt, ...
                        ind_mep,MSO_MEP_all,MSO_MEP_mean,AMT_mean);
                end
            end
            if app.CheckBox_12.Value==1
                text_current=app.EditField_12.Value;
                if ~isempty(text_current) && ~strcmp(text_current,'NaN')
                    pages=str2double(strsplit(app.EditField_12.Value,','));
                    mso=app.AMTMSOEditField_21.Value;
                    amt=string(app.CheckBox_12.Text);
                    [MSO_MEP_all,MSO_MEP_mean,AMT_mean] = RC_prep(app,pages,mso,amt, ...
                        ind_mep,MSO_MEP_all,MSO_MEP_mean,AMT_mean);
                end
            end
            if app.CheckBox_13.Value==1
                text_current=app.EditField_13.Value;
                if ~isempty(text_current) && ~strcmp(text_current,'NaN')
                    pages=str2double(strsplit(app.EditField_13.Value,','));
                    mso=app.AMTMSOEditField_22.Value;
                    amt=string(app.CheckBox_13.Text);
                    [MSO_MEP_all,MSO_MEP_mean,AMT_mean] = RC_prep(app,pages,mso,amt, ...
                        ind_mep,MSO_MEP_all,MSO_MEP_mean,AMT_mean);
                end
            end
            if app.CheckBox_14.Value==1
                text_current=app.EditField_14.Value;
                if ~isempty(text_current) && ~strcmp(text_current,'NaN')
                    pages=str2double(strsplit(app.EditField_14.Value,','));
                    mso=app.AMTMSOEditField_23.Value;
                    amt=string(app.CheckBox_14.Text);
                    [MSO_MEP_all,MSO_MEP_mean,AMT_mean] = RC_prep(app,pages,mso,amt, ...
                        ind_mep,MSO_MEP_all,MSO_MEP_mean,AMT_mean);
                end
            end
            if app.CheckBox_15.Value==1
                text_current=app.EditField_15.Value;
                if ~isempty(text_current) && ~strcmp(text_current,'NaN')
                    pages=str2double(strsplit(app.EditField_15.Value,','));
                    mso=app.AMTMSOEditField_24.Value;
                    amt=string(app.CheckBox_15.Text);
                    [MSO_MEP_all,MSO_MEP_mean,AMT_mean] = RC_prep(app,pages,mso,amt, ...
                        ind_mep,MSO_MEP_all,MSO_MEP_mean,AMT_mean);
                end
            end
            if app.CheckBox_16.Value==1
                text_current=app.EditField_16.Value;
                if ~isempty(text_current) && ~strcmp(text_current,'NaN')
                    pages=str2double(strsplit(app.EditField_16.Value,','));
                    mso=app.AMTMSOEditField_25.Value;
                    amt=string(app.CheckBox_16.Text);
                    [MSO_MEP_all,MSO_MEP_mean,AMT_mean] = RC_prep(app,pages,mso,amt, ...
                        ind_mep,MSO_MEP_all,MSO_MEP_mean,AMT_mean);
                end
            end
            if app.CheckBox_17.Value==1
                text_current=app.EditField_17.Value;
                if ~isempty(text_current) && ~strcmp(text_current,'NaN')
                    pages=str2double(strsplit(app.EditField_17.Value,','));
                    mso=app.AMTMSOEditField_26.Value;
                    amt=string(app.CheckBox_17.Text);
                    [MSO_MEP_all,MSO_MEP_mean,AMT_mean] = RC_prep(app,pages,mso,amt, ...
                        ind_mep,MSO_MEP_all,MSO_MEP_mean,AMT_mean);
                end
            end
            if app.CheckBox_18.Value==1
                text_current=app.EditField_18.Value;
                if ~isempty(text_current) && ~strcmp(text_current,'NaN')
                    pages=str2double(strsplit(app.EditField_18.Value,','));
                    mso=app.AMTMSOEditField_27.Value;
                    amt=string(app.CheckBox_18.Text);
                    [MSO_MEP_all,MSO_MEP_mean,AMT_mean] = RC_prep(app,pages,mso,amt, ...
                        ind_mep,MSO_MEP_all,MSO_MEP_mean,AMT_mean);
                end
            end
            
            if ~isempty(MSO_MEP_mean)
                [~,idx] = sort(MSO_MEP_mean(:,1)); % sort just the MSO column
                sorted_MSO_MEP_mean = MSO_MEP_mean(idx,:);   % sort the whole matrix using the sort indices
                sorted_AMT_mean=AMT_mean(idx);
                
                % fit sigmoid function
                if size(sorted_MSO_MEP_mean,1)>4
                    fun = @(beta,x)beta(1)+(beta(2))./(1+exp((beta(3)-x)/beta(4)));
                    x=sorted_MSO_MEP_mean(:,1);
                    RC_mean_sort=sorted_MSO_MEP_mean(:,2);
                    emgBase=0;
                    mepPlat=max(RC_mean_sort);
                    diffs = RC_mean_sort-mepPlat/2;
                    diffs = diffs(diffs>=0);
                    sFifty = find(min(diffs) == RC_mean_sort-mepPlat/2);
                    sFifty = x(sFifty);
                    k=round(mean(diff(x)));
                    guesses = [emgBase mepPlat sFifty k];
                    opts = statset('MaxIter',100000);
                    [beta,R,J,CovB,MSE,ErrorModelInfo] = nlinfit(x,RC_mean_sort,fun,guesses,opts);
                    
                    new_x=x(1):x(end)
                    new_y = fun(beta, new_x)
                    slope=1/beta(4);
                    auc=trapz(new_x,new_y);
                end

%                 sorted_MSO_MEP_mean(:,3)=sorted_MSO_MEP_mean(:,2)-min(sorted_MSO_MEP_mean(:,2));
%                 sorted_MSO_MEP_mean(:,3)=sorted_MSO_MEP_mean(:,2);
%                 mep_max=max(sorted_MSO_MEP_mean(:,3));
%                 all=sorted_MSO_MEP_mean(:,3);
%                 cr=1/2*mep_max;
%                 greater_ind=ge(sorted_MSO_MEP_mean(:,3),1/2*mep_max);
%                 [~,ind_half]=min(abs(sorted_MSO_MEP_mean(:,3)-1/2*mep_max));
%                 mso_athalf=sorted_MSO_MEP_mean(ind_half,1);
%                 if ind_half==length(sorted_MSO_MEP_mean(:,2)) || ind_half==1
%                     slope=[];
%                     sigmoid_y=[];
%                 else
%                     slope=(sorted_MSO_MEP_mean(ind_half+1,3)-sorted_MSO_MEP_mean(ind_half-1,3))/(sorted_MSO_MEP_mean(ind_half+1,1)-sorted_MSO_MEP_mean(ind_half-1,1));
%                     for i=1:length(sorted_MSO_MEP_mean(:,1))
%                         sigmoid_y(i)=mep_max/(1+exp((mso_athalf-sorted_MSO_MEP_mean(i,1))/slope));
%                     end
%                 end
                
                cla(app.UIAxes);
                plot(app.UIAxes,MSO_MEP_all(:,1),MSO_MEP_all(:,2),'.b','MarkerSize',20);
                hold(app.UIAxes,'on');
                plot(app.UIAxes,sorted_MSO_MEP_mean(:,1),sorted_MSO_MEP_mean(:,2),'o-r','MarkerSize',20,"linewidth",1);
                hold(app.UIAxes,'on');
                
                if size(sorted_MSO_MEP_mean,1)>4
                     hold(app.UIAxes,'on');
                     plot(app.UIAxes,new_x,new_y,'linewidth',2,'color','green');
                end
                    
                   
%                     plot(app.UIAxes,[sorted_MSO_MEP_mean(ind_half+1,1),sorted_MSO_MEP_mean(ind_half-1,1)],...
%                         [sorted_MSO_MEP_mean(ind_half+1,2),sorted_MSO_MEP_mean(ind_half-1,2)],'-g','linewidth',3);
%                 end
                
                hold(app.UIAxes,'on');
                %xticks(app.UIAxes,sorted_MSO_MEP_mean(:,1))
                labelArray = [compose('%.0f',sorted_MSO_MEP_mean(:,1)');sorted_AMT_mean'];
                
                tickLabels = strtrim(sprintf('%s%s\n', labelArray(:)));
                
                
                set(app.UIAxes,'xtick',sorted_MSO_MEP_mean(:,1),'xticklabel',tickLabels);
                ylabel(app.UIAxes,'mV');
                xlabel(app.UIAxes,'%MSO [%AMT]');
                title(app.UIAxes,'Recruitment Curve','fontsize',30)
            else
                cla(app.UIAxes);
                text(app.UIAxes,0.5,0.5,'Please select at least one AMT option(s)','Units', 'Normalized', 'fontsize', 20,...
                    'HorizontalAlignment', 'Center');
            end
        end

        % Value changed function: EditField_1
        function EditField_1ValueChanged(app, event)
            value = app.EditField_1.Value;
            if length(value)<=3
                values=[str2double(value):str2double(value)+9];
                app.EditField_1.Value=regexprep(num2str(values),'\s+',',');
            end
        end

        % Value changed function: EditField_2
        function EditField_2ValueChanged(app, event)
            value = app.EditField_2.Value;
            if length(value)<=3
                values=[str2double(value):str2double(value)+9];
                app.EditField_2.Value=regexprep(num2str(values),'\s+',',');
            end
        end

        % Value changed function: EditField_3
        function EditField_3ValueChanged(app, event)
            value = app.EditField_3.Value;
            if length(value)<=3
                values=[str2double(value):str2double(value)+9];
                app.EditField_3.Value=regexprep(num2str(values),'\s+',',');
            end
        end

        % Value changed function: EditField_4
        function EditField_4ValueChanged(app, event)
            value = app.EditField_4.Value;
            if length(value)<=3
                values=[str2double(value):str2double(value)+9];
                app.EditField_4.Value=regexprep(num2str(values),'\s+',',');
            end
        end

        % Value changed function: EditField_5
        function EditField_5ValueChanged(app, event)
            value = app.EditField_5.Value;
            if length(value)<=3
                values=[str2double(value):str2double(value)+9];
                app.EditField_5.Value=regexprep(num2str(values),'\s+',',');
            end
        end

        % Value changed function: EditField_6
        function EditField_6ValueChanged(app, event)
            value = app.EditField_6.Value;
            if length(value)<=3
                values=[str2double(value):str2double(value)+9];
                app.EditField_6.Value=regexprep(num2str(values),'\s+',',');
            end
        end

        % Value changed function: EditField_7
        function EditField_7ValueChanged(app, event)
            value = app.EditField_7.Value;
            if length(value)<=3
                values=[str2double(value):str2double(value)+9];
                app.EditField_7.Value=regexprep(num2str(values),'\s+',',');
            end
        end

        % Value changed function: EditField_8
        function EditField_8ValueChanged(app, event)
            value = app.EditField_8.Value;
            if length(value)<=3
                values=[str2double(value):str2double(value)+9];
                app.EditField_8.Value=regexprep(num2str(values),'\s+',',');
            end
        end

        % Value changed function: EditField_9
        function EditField_9ValueChanged(app, event)
            value = app.EditField_9.Value;
            if length(value)<=3
                values=[str2double(value):str2double(value)+9];
                app.EditField_9.Value=regexprep(num2str(values),'\s+',',');
            end
        end

        % Value changed function: EditField_10
        function EditField_10ValueChanged(app, event)
            value = app.EditField_10.Value;
            if length(value)<=3
                values=[str2double(value):str2double(value)+9];
                app.EditField_10.Value=regexprep(num2str(values),'\s+',',');
            end
        end

        % Value changed function: EditField_11
        function EditField_11ValueChanged(app, event)
            value = app.EditField_11.Value;
            if length(value)<=3
                values=[str2double(value):str2double(value)+9];
                app.EditField_11.Value=regexprep(num2str(values),'\s+',',');
            end
        end

        % Value changed function: EditField_12
        function EditField_12ValueChanged(app, event)
            value = app.EditField_12.Value;
            if length(value)<=3
                values=[str2double(value):str2double(value)+9];
                app.EditField_12.Value=regexprep(num2str(values),'\s+',',');
            end
        end

        % Value changed function: EditField_13
        function EditField_13ValueChanged(app, event)
            value = app.EditField_13.Value;
            if length(value)<=3
                values=[str2double(value):str2double(value)+9];
                app.EditField_13.Value=regexprep(num2str(values),'\s+',',');
            end
        end

        % Value changed function: EditField_14
        function EditField_14ValueChanged(app, event)
            value = app.EditField_14.Value;
            if length(value)<=3
                values=[str2double(value):str2double(value)+9];
                app.EditField_14.Value=regexprep(num2str(values),'\s+',',');
            end
        end

        % Value changed function: EditField_15
        function EditField_15ValueChanged(app, event)
            value = app.EditField_15.Value;
            if length(value)<=3
                values=[str2double(value):str2double(value)+9];
                app.EditField_15.Value=regexprep(num2str(values),'\s+',',');
            end
        end

        % Value changed function: EditField_16
        function EditField_16ValueChanged(app, event)
            value = app.EditField_16.Value;
            if length(value)<=3
                values=[str2double(value):str2double(value)+9];
                app.EditField_16.Value=regexprep(num2str(values),'\s+',',');
            end
        end

        % Value changed function: CheckBox
        function CheckBoxValueChanged(app, event)
            value = app.CheckBox.Value;
            switch value
                case 1
                    app.AMTMSOEditField_2.Enable='On';
                    app.EditField_1.Enable='On';
                    app.EditField_1.FontSize=9;
                    app.CheckBox.FontColor=app.color_enable;
                    app.CheckBox.FontWeight='bold';
                case 0
                    app.AMTMSOEditField_2.Enable='Off';
                    app.EditField_1.Enable='Off';
                    app.CheckBox.FontColor='black';
                    app.CheckBox.FontWeight='normal';
            end
        end

        % Value changed function: CheckBox_2
        function CheckBox_2ValueChanged(app, event)
            value = app.CheckBox_2.Value;
            switch value
                case 1
                    app.AMTMSOEditField_3.Enable='On';
                    app.EditField_2.Enable='On';
                    app.EditField_2.FontSize=9;
                    app.CheckBox_2.FontColor=app.color_enable;
                    app.CheckBox_2.FontWeight='bold';
                case 0
                    app.AMTMSOEditField_3.Enable='Off';
                    app.EditField_2.Enable='Off';
                    app.CheckBox_2.FontColor='black';
                    app.CheckBox_2.FontWeight='normal';
            end
        end

        % Value changed function: CheckBox_3
        function CheckBox_3ValueChanged(app, event)
            value = app.CheckBox_3.Value;
            switch value
                case 1
                    app.AMTMSOEditField_4.Enable='On';
                    app.EditField_3.Enable='On';
                    app.EditField_3.FontSize=9;
                    app.CheckBox_3.FontColor=app.color_enable;
                    app.CheckBox_3.FontWeight='bold';
                case 0
                    app.AMTMSOEditField_4.Enable='Off';
                    app.EditField_3.Enable='Off';
                    app.CheckBox_3.FontColor='black';
                    app.CheckBox_3.FontWeight='normal';
            end
        end

        % Value changed function: CheckBox_4
        function CheckBox_4ValueChanged(app, event)
            value = app.CheckBox_4.Value;
            switch value
                case 1
                    app.AMTMSOEditField_5.Enable='On';
                    app.EditField_4.Enable='On';
                    app.EditField_4.FontSize=9;
                    app.CheckBox_4.FontColor=app.color_enable;
                    app.CheckBox_4.FontWeight='bold';
                case 0
                    app.AMTMSOEditField_5.Enable='Off';
                    app.EditField_4.Enable='Off';
                    app.CheckBox_4.FontColor='black';
                    app.CheckBox_4.FontWeight='normal';
            end
        end

        % Value changed function: CheckBox_5
        function CheckBox_5ValueChanged(app, event)
            value = app.CheckBox_5.Value;
            switch value
                case 1
                    app.AMTMSOEditField_6.Enable='On';
                    app.EditField_5.Enable='On';
                    app.EditField_5.FontSize=9;
                    app.CheckBox_5.FontColor=app.color_enable;
                    app.CheckBox_5.FontWeight='bold';
                case 0
                    app.AMTMSOEditField_6.Enable='Off';
                    app.EditField_5.Enable='Off';
                    app.CheckBox_5.FontColor='black';
                    app.CheckBox_5.FontWeight='normal';
            end
        end

        % Value changed function: CheckBox_6
        function CheckBox_6ValueChanged(app, event)
            value = app.CheckBox_6.Value;
            switch value
                case 1
                    app.AMTMSOEditField_7.Enable='On';
                    app.EditField_6.Enable='On';
                    app.EditField_6.FontSize=9;
                    app.CheckBox_6.FontColor=app.color_enable;
                    app.CheckBox_6.FontWeight='bold';
                case 0
                    app.AMTMSOEditField_7.Enable='Off';
                    app.EditField_6.Enable='Off';
                    app.CheckBox_6.FontColor='black';
                    app.CheckBox_6.FontWeight='normal';
            end
        end

        % Value changed function: CheckBox_7
        function CheckBox_7ValueChanged(app, event)
            value = app.CheckBox_7.Value;
            switch value
                case 1
                    app.AMTMSOEditField_8.Enable='On';
                    app.EditField_7.Enable='On';
                    app.EditField_7.FontSize=9;
                    app.CheckBox_7.FontColor=app.color_enable;
                    app.CheckBox_7.FontWeight='bold';
                case 0
                    app.AMTMSOEditField_8.Enable='Off';
                    app.EditField_7.Enable='Off';
                    app.CheckBox_7.FontColor='black';
                    app.CheckBox_7.FontWeight='normal';
            end
        end

        % Value changed function: CheckBox_8
        function CheckBox_8ValueChanged(app, event)
            value = app.CheckBox_8.Value;
            switch value
                case 1
                    app.AMTMSOEditField_9.Enable='On';
                    app.EditField_8.Enable='On';
                    app.EditField_8.FontSize=9;
                    app.CheckBox_8.FontColor=app.color_enable;
                    app.CheckBox_8.FontWeight='bold';
                case 0
                    app.AMTMSOEditField_9.Enable='Off';
                    app.EditField_8.Enable='Off';
                    app.CheckBox_8.FontColor='black';
                    app.CheckBox_8.FontWeight='normal';
            end
        end

        % Value changed function: CheckBox_9
        function CheckBox_9ValueChanged(app, event)
            value = app.CheckBox_9.Value;
            switch value
                case 1
                    app.AMTMSOEditField_18.Enable='On';
                    app.EditField_9.Enable='On';
                    app.EditField_9.FontSize=9;
                    app.CheckBox_9.FontColor=app.color_enable;
                    app.CheckBox_9.FontWeight='bold';
                case 0
                    app.AMTMSOEditField_18.Enable='Off';
                    app.EditField_9.Enable='Off';
                    app.CheckBox_9.FontColor='black';
                    app.CheckBox_9.FontWeight='normal';
            end
        end

        % Value changed function: CheckBox_10
        function CheckBox_10ValueChanged(app, event)
            value = app.CheckBox_10.Value;
            switch value
                case 1
                    app.AMTMSOEditField_19.Enable='On';
                    app.EditField_10.Enable='On';
                    app.EditField_10.FontSize=9;
                    app.CheckBox_10.FontColor=app.color_enable;
                    app.CheckBox_10.FontWeight='bold';
                case 0
                    app.AMTMSOEditField_19.Enable='Off';
                    app.EditField_10.Enable='Off';
                    app.CheckBox_10.FontColor='black';
                    app.CheckBox_10.FontWeight='normal';
            end
        end

        % Value changed function: CheckBox_11
        function CheckBox_11ValueChanged(app, event)
            value = app.CheckBox_11.Value;
            switch value
                case 1
                    app.AMTMSOEditField_20.Enable='On';
                    app.EditField_11.Enable='On';
                    app.EditField_11.FontSize=9;
                    app.CheckBox_11.FontColor=app.color_enable;
                    app.CheckBox_11.FontWeight='bold';
                case 0
                    app.AMTMSOEditField_20.Enable='Off';
                    app.EditField_11.Enable='Off';
                    app.CheckBox_11.FontColor='black';
                    app.CheckBox_11.FontWeight='normal';
            end
        end

        % Value changed function: CheckBox_12
        function CheckBox_12ValueChanged(app, event)
            value = app.CheckBox_12.Value;
            switch value
                case 1
                    app.AMTMSOEditField_21.Enable='On';
                    app.EditField_12.Enable='On';
                    app.EditField_12.FontSize=9;
                    app.CheckBox_12.FontColor=app.color_enable;
                    app.CheckBox_12.FontWeight='bold';
                case 0
                    app.AMTMSOEditField_21.Enable='Off';
                    app.EditField_12.Enable='Off';
                    app.CheckBox_12.FontColor='black';
                    app.CheckBox_12.FontWeight='normal';
            end
        end

        % Value changed function: CheckBox_13
        function CheckBox_13ValueChanged(app, event)
            value = app.CheckBox_13.Value;
            switch value
                case 1
                    app.AMTMSOEditField_22.Enable='On';
                    app.EditField_13.Enable='On';
                    app.EditField_13.FontSize=9;
                    app.CheckBox_13.FontColor=app.color_enable;
                    app.CheckBox_13.FontWeight='bold';
                case 0
                    app.AMTMSOEditField_22.Enable='Off';
                    app.EditField_13.Enable='Off';
                    app.CheckBox_13.FontColor='black';
                    app.CheckBox_13.FontWeight='normal';
            end
        end

        % Value changed function: CheckBox_14
        function CheckBox_14ValueChanged(app, event)
            value = app.CheckBox_14.Value;
            switch value
                case 1
                    app.AMTMSOEditField_23.Enable='On';
                    app.EditField_14.Enable='On';
                    app.EditField_14.FontSize=9;
                    app.CheckBox_14.FontColor=app.color_enable;
                    app.CheckBox_14.FontWeight='bold';
                case 0
                    app.AMTMSOEditField_23.Enable='Off';
                    app.EditField_14.Enable='Off';
                    app.CheckBox_14.FontColor='black';
                    app.CheckBox_14.FontWeight='normal';
            end
        end

        % Value changed function: CheckBox_15
        function CheckBox_15ValueChanged(app, event)
            value = app.CheckBox_15.Value;
            switch value
                case 1
                    app.AMTMSOEditField_24.Enable='On';
                    app.EditField_15.Enable='On';
                    app.EditField_15.FontSize=9;
                    app.CheckBox_15.FontColor=app.color_enable;
                    app.CheckBox_15.FontWeight='bold';
                case 0
                    app.AMTMSOEditField_24.Enable='Off';
                    app.EditField_15.Enable='Off';
                    app.CheckBox_15.FontColor='black';
                    app.CheckBox_15.FontWeight='normal';
            end
        end

        % Value changed function: CheckBox_16
        function CheckBox_16ValueChanged(app, event)
            value = app.CheckBox_16.Value;
            switch value
                case 1
                    app.AMTMSOEditField_25.Enable='On';
                    app.EditField_16.Enable='On';
                    app.EditField_16.FontSize=9;
                    app.CheckBox_16.FontColor=app.color_enable;
                    app.CheckBox_16.FontWeight='bold';
                case 0
                    app.AMTMSOEditField_25.Enable='Off';
                    app.EditField_16.Enable='Off';
                    app.CheckBox_16.FontColor='black';
                    app.CheckBox_16.FontWeight='normal';
            end
        end

        % Value changed function: CheckBox_17
        function CheckBox_17ValueChanged(app, event)
            value = app.CheckBox_17.Value;
            switch value
                case 1
                    app.AMTMSOEditField_26.Enable='On';
                    app.EditField_17.Enable='On';
                    app.EditField_17.FontSize=9;
                    app.CheckBox_17.FontColor=app.color_enable;
                    app.CheckBox_17.FontWeight='bold';
                case 0
                    app.AMTMSOEditField_26.Enable='Off';
                    app.EditField_17.Enable='Off';
                    app.CheckBox_17.FontColor='black';
                    app.CheckBox_17.FontWeight='normal';
            end
        end

        % Value changed function: CheckBox_18
        function CheckBox_18ValueChanged(app, event)
            value = app.CheckBox_18.Value;
            switch value
                case 1
                    app.AMTMSOEditField_27.Enable='On';
                    app.EditField_18.Enable='On';
                    app.EditField_18.FontSize=9;
                    app.CheckBox_18.FontColor=app.color_enable;
                    app.CheckBox_18.FontWeight='bold';
                case 0
                    app.AMTMSOEditField_27.Enable='Off';
                    app.EditField_18.Enable='Off';
                    app.CheckBox_18.FontColor='black';
                    app.CheckBox_18.FontWeight='normal';
            end
        end

        % Value changed function: EditField_17
        function EditField_17ValueChanged(app, event)
            value = app.EditField_17.Value;
            if length(value)<=3
                values=[str2double(value):str2double(value)+9];
                app.EditField_17.Value=regexprep(num2str(values),'\s+',',');
            end
        end

        % Value changed function: EditField_18
        function EditField_18ValueChanged(app, event)
            value = app.EditField_18.Value;
            if length(value)<=3
                values=[str2double(value):str2double(value)+9];
                app.EditField_18.Value=regexprep(num2str(values),'\s+',',');
            end
        end

        % Value changed function: NewAMTCheckBox
        function NewAMTCheckBoxValueChanged(app, event)
            value = app.NewAMTCheckBox.Value;
            if value ==1
                app.AMTMSOEditField_28.Visible='On';
                app.CheckBox_17.Visible='On';
                app.CheckBox_18.Visible='On';
                app.AMTMSOEditField_26.Visible='On';
                app.AMTMSOEditField_27.Visible='On';
                app.EditField_17.Visible='On';
                app.EditField_18.Visible='On';
                app.CheckBox_17.Enable='On';
                app.CheckBox_18.Enable='On';
                app.AMTMSOEditField_26.Editable='On';
                app.AMTMSOEditField_27.Editable='On';
                app.EditField_17.Editable='On';
                app.EditField_18.Editable='On';
                
            elseif value ==0
                app.AMTMSOEditField_28.Visible='Off';
                app.CheckBox_17.Visible='Off';
                app.CheckBox_18.Visible='Off';
                app.AMTMSOEditField_26.Visible='Off';
                app.AMTMSOEditField_27.Visible='Off';
                app.EditField_17.Visible='Off';
                app.EditField_18.Visible='Off';
            end
        end

        % Value changed function: AMTMSOEditField_28
        function AMTMSOEditField_28ValueChanged(app, event)
            value = app.AMTMSOEditField_28.Value;
            app.AMTMSOEditField_26.Value=round(double(1.2*value));
            app.AMTMSOEditField_27.Value=round(double(0.9*value));
        end

        % Value changed function: AMTMSOEditField
        function AMTMSOEditFieldValueChanged(app, event)
            value = app.AMTMSOEditField.Value;
            app.AMTMSOEditField_2.Value=round(1.2*value);
            app.AMTMSOEditField_3.Value=round(1.1*value);
            app.AMTMSOEditField_4.Value=round(1.5*value);
            app.AMTMSOEditField_5.Value=round(1.3*value);
            app.AMTMSOEditField_6.Value=round(1.4*value);
            app.AMTMSOEditField_7.Value=round(0.9*value);
            app.AMTMSOEditField_8.Value=round(1*value);
            app.AMTMSOEditField_9.Value=round(1.6*value);
            app.AMTMSOEditField_18.Value=round(1.7*value);
            app.AMTMSOEditField_19.Value=round(1.8*value);
            app.AMTMSOEditField_20.Value=round(1.9*value);
            app.AMTMSOEditField_21.Value=round(2.0*value);
            app.AMTMSOEditField_22.Value=round(2.1*value);
            app.AMTMSOEditField_23.Value=round(2.2*value);
            app.AMTMSOEditField_24.Value=round(0.8*value);
            app.AMTMSOEditField_25.Value=round(0.7*value);
        end

        % Button pushed function: UnlinkLabChartButton
        function UnlinkLabChartButtonPushed(app, event)
            global gLCDoc1
            gLCDoc1.unregisterallevents
            disp(gLCDoc1.eventlisteners)
            gLCDoc1.release
            
        end

        % Value changed function: CheckBox_99
        function CheckBox_99ValueChanged(app, event)
            value = app.CheckBox_99.Value;
            switch value
                case 1
                    app.AMTMSOEditField_149.FontWeight='bold';
                    app.AMTMSOEditField_149.FontColor='green';
                case 0
                    app.AMTMSOEditField_149.FontWeight='normal';
                    app.AMTMSOEditField_149.FontColor='black';
            end
        end

        % Value changed function: CheckBox_100
        function CheckBox_100ValueChanged(app, event)
            value = app.CheckBox_100.Value;
            switch value
                case 1
                    app.AMTMSOEditField_150.FontWeight='bold';
                    app.AMTMSOEditField_150.FontColor='green';
                case 0
                    app.AMTMSOEditField_150.FontWeight='normal';
                    app.AMTMSOEditField_150.FontColor='black';
            end
        end

        % Value changed function: CheckBox_101
        function CheckBox_101ValueChanged(app, event)
            value = app.CheckBox_101.Value;
            switch value
                case 1
                    app.AMTMSOEditField_151.FontWeight='bold';
                    app.AMTMSOEditField_151.FontColor='green';
                case 0
                    app.AMTMSOEditField_151.FontWeight='normal';
                    app.AMTMSOEditField_151.FontColor='black';
            end
        end

        % Value changed function: CheckBox_102
        function CheckBox_102ValueChanged(app, event)
            value = app.CheckBox_102.Value;
            switch value
                case 1
                    app.AMTMSOEditField_152.FontWeight='bold';
                    app.AMTMSOEditField_152.FontColor='green';
                case 0
                    app.AMTMSOEditField_152.FontWeight='normal';
                    app.AMTMSOEditField_152.FontColor='black';
            end
        end

        % Value changed function: CheckBox_103
        function CheckBox_103ValueChanged(app, event)
            value = app.CheckBox_103.Value;
            switch value
                case 1
                    app.AMTMSOEditField_153.FontWeight='bold';
                    app.AMTMSOEditField_153.FontColor='green';
                case 0
                    app.AMTMSOEditField_153.FontWeight='normal';
                    app.AMTMSOEditField_153.FontColor='black';
            end
        end

        % Value changed function: CheckBox_104
        function CheckBox_104ValueChanged(app, event)
            value = app.CheckBox_104.Value;
            switch value
                case 1
                    app.AMTMSOEditField_154.FontWeight='bold';
                    app.AMTMSOEditField_154.FontColor='green';
                case 0
                    app.AMTMSOEditField_154.FontWeight='normal';
                    app.AMTMSOEditField_154.FontColor='black';
            end
        end

        % Value changed function: CheckBox_105
        function CheckBox_105ValueChanged(app, event)
            value = app.CheckBox_105.Value;
            switch value
                case 1
                    app.AMTMSOEditField_155.FontWeight='bold';
                    app.AMTMSOEditField_155.FontColor='green';
                case 0
                    app.AMTMSOEditField_155.FontWeight='normal';
                    app.AMTMSOEditField_155.FontColor='black';
            end
        end

        % Value changed function: CheckBox_106
        function CheckBox_106ValueChanged(app, event)
            value = app.CheckBox_106.Value;
            switch value
                case 1
                    app.AMTMSOEditField_156.FontWeight='bold';
                    app.AMTMSOEditField_156.FontColor='green';
                case 0
                    app.AMTMSOEditField_156.FontWeight='normal';
                    app.AMTMSOEditField_156.FontColor='black';
            end
        end

        % Value changed function: CheckBox_107
        function CheckBox_107ValueChanged(app, event)
            value = app.CheckBox_107.Value;
            switch value
                case 1
                    app.AMTMSOEditField_157.FontWeight='bold';
                    app.AMTMSOEditField_157.FontColor='green';
                case 0
                    app.AMTMSOEditField_157.FontWeight='normal';
                    app.AMTMSOEditField_157.FontColor='black';
            end
        end

        % Value changed function: CheckBox_108
        function CheckBox_108ValueChanged(app, event)
            value = app.CheckBox_108.Value;
            switch value
                case 1
                    app.AMTMSOEditField_158.FontWeight='bold';
                    app.AMTMSOEditField_158.FontColor='green';
                case 0
                    app.AMTMSOEditField_158.FontWeight='normal';
                    app.AMTMSOEditField_158.FontColor='black';
            end
        end

        % Value changed function: CheckBox_109
        function CheckBox_109ValueChanged(app, event)
            value = app.CheckBox_109.Value;
            switch value
                case 1
                    app.AMTMSOEditField_159.FontWeight='bold';
                    app.AMTMSOEditField_159.FontColor='green';
                case 0
                    app.AMTMSOEditField_159.FontWeight='normal';
                    app.AMTMSOEditField_159.FontColor='black';
            end
        end

        % Value changed function: CheckBox_110
        function CheckBox_110ValueChanged(app, event)
            value = app.CheckBox_110.Value;
            switch value
                case 1
                    app.AMTMSOEditField_160.FontWeight='bold';
                    app.AMTMSOEditField_160.FontColor='green';
                case 0
                    app.AMTMSOEditField_160.FontWeight='normal';
                    app.AMTMSOEditField_160.FontColor='black';
            end
        end

        % Value changed function: CheckBox_111
        function CheckBox_111ValueChanged(app, event)
            value = app.CheckBox_111.Value;
            switch value
                case 1
                    app.AMTMSOEditField_161.FontWeight='bold';
                    app.AMTMSOEditField_161.FontColor='green';
                case 0
                    app.AMTMSOEditField_161.FontWeight='normal';
                    app.AMTMSOEditField_161.FontColor='black';
            end
        end

        % Value changed function: CheckBox_112
        function CheckBox_112ValueChanged(app, event)
            value = app.CheckBox_112.Value;
            switch value
                case 1
                    app.AMTMSOEditField_162.FontWeight='bold';
                    app.AMTMSOEditField_162.FontColor='green';
                case 0
                    app.AMTMSOEditField_162.FontWeight='normal';
                    app.AMTMSOEditField_162.FontColor='black';
            end
        end

        % Value changed function: CheckBox_113
        function CheckBox_113ValueChanged(app, event)
            value = app.CheckBox_113.Value;
            switch value
                case 1
                    app.AMTMSOEditField_163.FontWeight='bold';
                    app.AMTMSOEditField_163.FontColor='green';
                case 0
                    app.AMTMSOEditField_163.FontWeight='normal';
                    app.AMTMSOEditField_163.FontColor='black';
            end
            
        end

        % Value changed function: CheckBox_114
        function CheckBox_114ValueChanged(app, event)
            value = app.CheckBox_114.Value;
            switch value
                case 1
                    app.AMTMSOEditField_164.FontWeight='bold';
                    app.AMTMSOEditField_164.FontColor='green';
                case 0
                    app.AMTMSOEditField_164.FontWeight='normal';
                    app.AMTMSOEditField_164.FontColor='black';
            end
        end

        % Value changed function: CheckBox_115
        function CheckBox_115ValueChanged(app, event)
            value = app.CheckBox_115.Value;
            switch value
                case 1
                    app.AMTMSOEditField_165.FontWeight='bold';
                    app.AMTMSOEditField_165.FontColor='green';
                case 0
                    app.AMTMSOEditField_165.FontWeight='normal';
                    app.AMTMSOEditField_165.FontColor='black';
            end
        end

        % Value changed function: CheckBox_116
        function CheckBox_116ValueChanged(app, event)
            value = app.CheckBox_116.Value;
            switch value
                case 1
                    app.AMTMSOEditField_166.FontWeight='bold';
                    app.AMTMSOEditField_166.FontColor='green';
                case 0
                    app.AMTMSOEditField_166.FontWeight='normal';
                    app.AMTMSOEditField_166.FontColor='black';
            end
        end

        % Value changed function: CheckBox_117
        function CheckBox_117ValueChanged(app, event)
            value = app.CheckBox_117.Value;
            switch value
                case 1
                    app.AMTMSOEditField_167.FontWeight='bold';
                    app.AMTMSOEditField_167.FontColor='green';
                case 0
                    app.AMTMSOEditField_167.FontWeight='normal';
                    app.AMTMSOEditField_167.FontColor='black';
            end
        end

        % Value changed function: CheckBox_118
        function CheckBox_118ValueChanged(app, event)
            value = app.CheckBox_118.Value;
            switch value
                case 1
                    app.AMTMSOEditField_168.FontWeight='bold';
                    app.AMTMSOEditField_168.FontColor='green';
                case 0
                    app.AMTMSOEditField_168.FontWeight='normal';
                    app.AMTMSOEditField_168.FontColor='black';
            end
        end

        % Value changed function: CheckBox_119
        function CheckBox_119ValueChanged(app, event)
            value = app.CheckBox_119.Value;
            switch value
                case 1
                    app.AMTMSOEditField_169.FontWeight='bold';
                    app.AMTMSOEditField_169.FontColor='green';
                case 0
                    app.AMTMSOEditField_169.FontWeight='normal';
                    app.AMTMSOEditField_169.FontColor='black';
            end
        end

        % Value changed function: CheckBox_120
        function CheckBox_120ValueChanged(app, event)
            value = app.CheckBox_120.Value;
            switch value
                case 1
                    app.AMTMSOEditField_170.FontWeight='bold';
                    app.AMTMSOEditField_170.FontColor='green';
                case 0
                    app.AMTMSOEditField_170.FontWeight='normal';
                    app.AMTMSOEditField_170.FontColor='black';
            end
        end

        % Value changed function: CheckBox_121
        function CheckBox_121ValueChanged(app, event)
            value = app.CheckBox_121.Value;
            switch value
                case 1
                    app.AMTMSOEditField_171.FontWeight='bold';
                    app.AMTMSOEditField_171.FontColor='green';
                case 0
                    app.AMTMSOEditField_171.FontWeight='normal';
                    app.AMTMSOEditField_171.FontColor='black';
            end
        end

        % Value changed function: CheckBox_122
        function CheckBox_122ValueChanged(app, event)
            value = app.CheckBox_122.Value;
            switch value
                case 1
                    app.AMTMSOEditField_172.FontWeight='bold';
                    app.AMTMSOEditField_172.FontColor='green';
                case 0
                    app.AMTMSOEditField_172.FontWeight='normal';
                    app.AMTMSOEditField_172.FontColor='black';
            end
        end

        % Value changed function: CheckBox_123
        function CheckBox_123ValueChanged(app, event)
            value = app.CheckBox_123.Value;
            switch value
                case 1
                    app.AMTMSOEditField_173.FontWeight='bold';
                    app.AMTMSOEditField_173.FontColor='green';
                case 0
                    app.AMTMSOEditField_173.FontWeight='normal';
                    app.AMTMSOEditField_173.FontColor='black';
            end
        end

        % Value changed function: CheckBox_124
        function CheckBox_124ValueChanged(app, event)
            value = app.CheckBox_124.Value;
            switch value
                case 1
                    app.AMTMSOEditField_174.FontWeight='bold';
                    app.AMTMSOEditField_174.FontColor='green';
                case 0
                    app.AMTMSOEditField_174.FontWeight='normal';
                    app.AMTMSOEditField_174.FontColor='black';
            end
        end

        % Value changed function: CheckBox_125
        function CheckBox_125ValueChanged(app, event)
            value = app.CheckBox_125.Value;
            switch value
                case 1
                    app.AMTMSOEditField_175.FontWeight='bold';
                    app.AMTMSOEditField_175.FontColor='green';
                case 0
                    app.AMTMSOEditField_175.FontWeight='normal';
                    app.AMTMSOEditField_175.FontColor='black';
            end
        end

        % Value changed function: CheckBox_126
        function CheckBox_126ValueChanged(app, event)
            value = app.CheckBox_126.Value;
            switch value
                case 1
                    app.AMTMSOEditField_176.FontWeight='bold';
                    app.AMTMSOEditField_176.FontColor='green';
                case 0
                    app.AMTMSOEditField_176.FontWeight='normal';
                    app.AMTMSOEditField_176.FontColor='black';
            end
        end

        % Value changed function: CheckBox_127
        function CheckBox_127ValueChanged(app, event)
            value = app.CheckBox_127.Value;
            switch value
                case 1
                    app.AMTMSOEditField_177.FontWeight='bold';
                    app.AMTMSOEditField_177.FontColor='green';
                case 0
                    app.AMTMSOEditField_177.FontWeight='normal';
                    app.AMTMSOEditField_177.FontColor='black';
            end
        end

        % Value changed function: CheckBox_128
        function CheckBox_128ValueChanged(app, event)
            value = app.CheckBox_128.Value;
            switch value
                case 1
                    app.AMTMSOEditField_178.FontWeight='bold';
                    app.AMTMSOEditField_178.FontColor='green';
                case 0
                    app.AMTMSOEditField_178.FontWeight='normal';
                    app.AMTMSOEditField_178.FontColor='black';
            end
        end

        % Value changed function: CheckBox_129
        function CheckBox_129ValueChanged(app, event)
            value = app.CheckBox_129.Value;
            switch value
                case 1
                    app.AMTMSOEditField_179.FontWeight='bold';
                    app.AMTMSOEditField_179.FontColor='green';
                case 0
                    app.AMTMSOEditField_179.FontWeight='normal';
                    app.AMTMSOEditField_179.FontColor='black';
            end
        end

        % Value changed function: CheckBox_130
        function CheckBox_130ValueChanged(app, event)
            value = app.CheckBox_130.Value;
            switch value
                case 1
                    app.AMTMSOEditField_180.FontWeight='bold';
                    app.AMTMSOEditField_180.FontColor='green';
                case 0
                    app.AMTMSOEditField_180.FontWeight='normal';
                    app.AMTMSOEditField_180.FontColor='black';
            end
        end

        % Value changed function: CheckBox_131
        function CheckBox_131ValueChanged(app, event)
            value = app.CheckBox_131.Value;
            switch value
                case 1
                    app.AMTMSOEditField_181.FontWeight='bold';
                    app.AMTMSOEditField_181.FontColor='green';
                case 0
                    app.AMTMSOEditField_181.FontWeight='normal';
                    app.AMTMSOEditField_181.FontColor='black';
            end
        end

        % Value changed function: CheckBox_132
        function CheckBox_132ValueChanged(app, event)
            value = app.CheckBox_132.Value;
            switch value
                case 1
                    app.AMTMSOEditField_182.FontWeight='bold';
                    app.AMTMSOEditField_182.FontColor='green';
                case 0
                    app.AMTMSOEditField_182.FontWeight='normal';
                    app.AMTMSOEditField_182.FontColor='black';
            end
        end

        % Value changed function: CheckBox_133
        function CheckBox_133ValueChanged(app, event)
            value = app.CheckBox_133.Value;
            switch value
                case 1
                    app.AMTMSOEditField_183.FontWeight='bold';
                    app.AMTMSOEditField_183.FontColor='green';
                case 0
                    app.AMTMSOEditField_183.FontWeight='normal';
                    app.AMTMSOEditField_183.FontColor='black';
            end
        end

        % Value changed function: CheckBox_134
        function CheckBox_134ValueChanged(app, event)
            value = app.CheckBox_134.Value;
            switch value
                case 1
                    app.AMTMSOEditField_184.FontWeight='bold';
                    app.AMTMSOEditField_184.FontColor='green';
                case 0
                    app.AMTMSOEditField_184.FontWeight='normal';
                    app.AMTMSOEditField_184.FontColor='black';
            end
        end

        % Value changed function: CheckBox_135
        function CheckBox_135ValueChanged(app, event)
            value = app.CheckBox_135.Value;
            switch value
                case 1
                    app.AMTMSOEditField_185.FontWeight='bold';
                    app.AMTMSOEditField_185.FontColor='green';
                case 0
                    app.AMTMSOEditField_185.FontWeight='normal';
                    app.AMTMSOEditField_185.FontColor='black';
            end
        end

        % Value changed function: CheckBox_136
        function CheckBox_136ValueChanged(app, event)
            value = app.CheckBox_136.Value;
            switch value
                case 1
                    app.AMTMSOEditField_186.FontWeight='bold';
                    app.AMTMSOEditField_186.FontColor='green';
                case 0
                    app.AMTMSOEditField_186.FontWeight='normal';
                    app.AMTMSOEditField_186.FontColor='black';
            end
        end

        % Value changed function: CheckBox_137
        function CheckBox_137ValueChanged(app, event)
            value = app.CheckBox_137.Value;
            switch value
                case 1
                    app.AMTMSOEditField_187.FontWeight='bold';
                    app.AMTMSOEditField_187.FontColor='green';
                case 0
                    app.AMTMSOEditField_187.FontWeight='normal';
                    app.AMTMSOEditField_187.FontColor='black';
            end
        end

        % Value changed function: CheckBox_138
        function CheckBox_138ValueChanged(app, event)
            value = app.CheckBox_138.Value;
            switch value
                case 1
                    app.AMTMSOEditField_188.FontWeight='bold';
                    app.AMTMSOEditField_188.FontColor='green';
                case 0
                    app.AMTMSOEditField_188.FontWeight='normal';
                    app.AMTMSOEditField_188.FontColor='black';
            end
        end

        % Value changed function: CheckBox_139
        function CheckBox_139ValueChanged(app, event)
            value = app.CheckBox_139.Value;
            switch value
                case 1
                    Weight='bold';
                    fontcol='green';
                    v=1;
                case 0
                    Weight='normal';
                    fontcol='black';
                    v=0;
            end
            app.CheckBox_99.Value=v;
            app.CheckBox_100.Value=v;
            app.CheckBox_101.Value=v;
            app.CheckBox_102.Value=v;
            app.CheckBox_103.Value=v;
            app.CheckBox_104.Value=v;
            app.CheckBox_105.Value=v;
            app.CheckBox_106.Value=v;
            app.CheckBox_107.Value=v;
            app.CheckBox_108.Value=v;
            app.AMTMSOEditField_149.FontWeight=Weight;
            app.AMTMSOEditField_149.FontColor=fontcol;
            app.AMTMSOEditField_150.FontWeight=Weight;
            app.AMTMSOEditField_150.FontColor=fontcol;
            app.AMTMSOEditField_151.FontWeight=Weight;
            app.AMTMSOEditField_151.FontColor=fontcol;
            app.AMTMSOEditField_152.FontWeight=Weight;
            app.AMTMSOEditField_152.FontColor=fontcol;
            app.AMTMSOEditField_153.FontWeight=Weight;
            app.AMTMSOEditField_153.FontColor=fontcol;
            app.AMTMSOEditField_154.FontWeight=Weight;
            app.AMTMSOEditField_154.FontColor=fontcol;
            app.AMTMSOEditField_155.FontWeight=Weight;
            app.AMTMSOEditField_155.FontColor=fontcol;
            app.AMTMSOEditField_156.FontWeight=Weight;
            app.AMTMSOEditField_156.FontColor=fontcol;
            app.AMTMSOEditField_157.FontWeight=Weight;
            app.AMTMSOEditField_157.FontColor=fontcol;
            app.AMTMSOEditField_158.FontWeight=Weight;
            app.AMTMSOEditField_158.FontColor=fontcol;
        end

        % Value changed function: CheckBox_140
        function CheckBox_140ValueChanged(app, event)
            value = app.CheckBox_140.Value;
            switch value
                case 1
                    Weight='bold';
                    fontcol='green';
                    v=1;
                case 0
                    Weight='normal';
                    fontcol='black';
                    v=0;
            end
            app.CheckBox_109.Value=v;
            app.CheckBox_110.Value=v;
            app.CheckBox_111.Value=v;
            app.CheckBox_112.Value=v;
            app.CheckBox_113.Value=v;
            app.CheckBox_114.Value=v;
            app.CheckBox_115.Value=v;
            app.CheckBox_116.Value=v;
            app.CheckBox_117.Value=v;
            app.CheckBox_118.Value=v;
            app.AMTMSOEditField_159.FontWeight=Weight;
            app.AMTMSOEditField_159.FontColor=fontcol;
            app.AMTMSOEditField_160.FontWeight=Weight;
            app.AMTMSOEditField_160.FontColor=fontcol;
            app.AMTMSOEditField_161.FontWeight=Weight;
            app.AMTMSOEditField_161.FontColor=fontcol;
            app.AMTMSOEditField_162.FontWeight=Weight;
            app.AMTMSOEditField_162.FontColor=fontcol;
            app.AMTMSOEditField_163.FontWeight=Weight;
            app.AMTMSOEditField_163.FontColor=fontcol;
            app.AMTMSOEditField_164.FontWeight=Weight;
            app.AMTMSOEditField_164.FontColor=fontcol;
            app.AMTMSOEditField_165.FontWeight=Weight;
            app.AMTMSOEditField_165.FontColor=fontcol;
            app.AMTMSOEditField_166.FontWeight=Weight;
            app.AMTMSOEditField_166.FontColor=fontcol;
            app.AMTMSOEditField_167.FontWeight=Weight;
            app.AMTMSOEditField_167.FontColor=fontcol;
            app.AMTMSOEditField_168.FontWeight=Weight;
            app.AMTMSOEditField_168.FontColor=fontcol;
        end

        % Value changed function: CheckBox_141
        function CheckBox_141ValueChanged(app, event)
            value = app.CheckBox_141.Value;
            switch value
                case 1
                    Weight='bold';
                    fontcol='green';
                    v=1;
                case 0
                    Weight='normal';
                    fontcol='black';
                    v=0;
            end
            app.CheckBox_119.Value=v;
            app.CheckBox_120.Value=v;
            app.CheckBox_121.Value=v;
            app.CheckBox_122.Value=v;
            app.CheckBox_123.Value=v;
            app.CheckBox_124.Value=v;
            app.CheckBox_125.Value=v;
            app.CheckBox_126.Value=v;
            app.CheckBox_127.Value=v;
            app.CheckBox_128.Value=v;
            app.AMTMSOEditField_169.FontWeight=Weight;
            app.AMTMSOEditField_169.FontColor=fontcol;
            app.AMTMSOEditField_170.FontWeight=Weight;
            app.AMTMSOEditField_170.FontColor=fontcol;
            app.AMTMSOEditField_171.FontWeight=Weight;
            app.AMTMSOEditField_171.FontColor=fontcol;
            app.AMTMSOEditField_172.FontWeight=Weight;
            app.AMTMSOEditField_172.FontColor=fontcol;
            app.AMTMSOEditField_173.FontWeight=Weight;
            app.AMTMSOEditField_173.FontColor=fontcol;
            app.AMTMSOEditField_174.FontWeight=Weight;
            app.AMTMSOEditField_174.FontColor=fontcol;
            app.AMTMSOEditField_175.FontWeight=Weight;
            app.AMTMSOEditField_175.FontColor=fontcol;
            app.AMTMSOEditField_176.FontWeight=Weight;
            app.AMTMSOEditField_176.FontColor=fontcol;
            app.AMTMSOEditField_177.FontWeight=Weight;
            app.AMTMSOEditField_177.FontColor=fontcol;
            app.AMTMSOEditField_178.FontWeight=Weight;
            app.AMTMSOEditField_178.FontColor=fontcol;
        end

        % Value changed function: CheckBox_142
        function CheckBox_142ValueChanged(app, event)
            value = app.CheckBox_142.Value;
            switch value
                case 1
                    Weight='bold';
                    fontcol='green';
                    v=1;
                case 0
                    Weight='normal';
                    fontcol='black';
                    v=0;
            end
            app.CheckBox_129.Value=v;
            app.CheckBox_130.Value=v;
            app.CheckBox_131.Value=v;
            app.CheckBox_132.Value=v;
            app.CheckBox_133.Value=v;
            app.CheckBox_134.Value=v;
            app.CheckBox_135.Value=v;
            app.CheckBox_136.Value=v;
            app.CheckBox_137.Value=v;
            app.CheckBox_138.Value=v;
            app.AMTMSOEditField_179.FontWeight=Weight;
            app.AMTMSOEditField_179.FontColor=fontcol;
            app.AMTMSOEditField_180.FontWeight=Weight;
            app.AMTMSOEditField_180.FontColor=fontcol;
            app.AMTMSOEditField_181.FontWeight=Weight;
            app.AMTMSOEditField_181.FontColor=fontcol;
            app.AMTMSOEditField_182.FontWeight=Weight;
            app.AMTMSOEditField_182.FontColor=fontcol;
            app.AMTMSOEditField_183.FontWeight=Weight;
            app.AMTMSOEditField_183.FontColor=fontcol;
            app.AMTMSOEditField_184.FontWeight=Weight;
            app.AMTMSOEditField_184.FontColor=fontcol;
            app.AMTMSOEditField_185.FontWeight=Weight;
            app.AMTMSOEditField_185.FontColor=fontcol;
            app.AMTMSOEditField_186.FontWeight=Weight;
            app.AMTMSOEditField_186.FontColor=fontcol;
            app.AMTMSOEditField_187.FontWeight=Weight;
            app.AMTMSOEditField_187.FontColor=fontcol;
            app.AMTMSOEditField_188.FontWeight=Weight;
            app.AMTMSOEditField_188.FontColor=fontcol;
        end

        % Value changed function: PlotHMRCButton
        function PlotHMRCButtonValueChanged(app, event)
            %% get the active LabChart
            try
                app.gLCApp = actxGetRunningServer('ADIChart.Application');
            catch err
                error('Please start LabChart before running this script.');
            end
            if (isempty(app.gLCApp.ActiveDocument))
                error('Please open a document in LabChart before running this script.');
            end
            app.gLCDoc = app.gLCApp.ActiveDocument;
            app.gLatestBlock = app.gLCDoc.NumberOfRecords;
            app.gBlockSecsPerTick = app.gLCDoc.GetRecordSecsPerTick(app.gLatestBlock-1);
            
            app.gChans=app.ChannelNoEditField.Value;%Channel Number
            app.time_prestim=app.ScopeViewtimerangemsEditField_2.Value; % time before stimulation in seconds
            app.time_poststim=app.ScopeViewtimerangemsEditField_3.Value;% time after stimulation in seconds
            app.mstartms=app.MmsEditField.Value;
            app.mendms=app.MmstoEditField.Value;
            app.hstartms=app.HmsEditField.Value;
            app.hendms=app.HmstoEditField.Value;
            app.subpage=app.SubpagebyEditField.Value;
            subpage=app.subpage;
            
            M_start_ind=(-app.time_prestim+app.mstartms)/1000*1/app.gBlockSecsPerTick+1+1;
            M_end_ind=(-app.time_prestim+app.mendms)/1000*1/app.gBlockSecsPerTick+1;
            H_start_ind=(-app.time_prestim+app.hstartms)/1000*1/app.gBlockSecsPerTick+1+1;
            H_end_ind=(-app.time_prestim+app.hendms)/1000*1/app.gBlockSecsPerTick+1;
            ind_m=[fix(M_start_ind):fix(M_end_ind)];
            ind_h=[fix(H_start_ind):fix(H_end_ind)];
            
            
            mA_M_H_all=[];
            mA_M_H_mean=[];
            
            
            %% obtain the page data
            if app.CheckBox_99.Value==1
                text_current=app.EditField_19.Value;
                if ~isempty(text_current) && ~strcmp(text_current,'NaN')
                    page=str2double(text_current);
                    mA=app.AMTMSOEditField_149.Value;
                    [mA_M_H_all,mA_M_H_mean] = Hreflex_RC_prep(app,page,subpage,mA, ...
                        ind_m,ind_h,mA_M_H_all,mA_M_H_mean);
                end
            end
            if app.CheckBox_100.Value==1
                text_current=app.EditField_20.Value;
                if ~isempty(text_current) && ~strcmp(text_current,'NaN')
                    page=str2double(text_current);
                    mA=app.AMTMSOEditField_150.Value;
                    [mA_M_H_all,mA_M_H_mean] = Hreflex_RC_prep(app,page,subpage,mA, ...
                        ind_m,ind_h,mA_M_H_all,mA_M_H_mean);
                end
            end
            if app.CheckBox_101.Value==1
                text_current=app.EditField_21.Value;
                if ~isempty(text_current) && ~strcmp(text_current,'NaN')
                    page=str2double(text_current);
                    mA=app.AMTMSOEditField_151.Value;
                    [mA_M_H_all,mA_M_H_mean] = Hreflex_RC_prep(app,page,subpage,mA, ...
                        ind_m,ind_h,mA_M_H_all,mA_M_H_mean);
                end
            end
            if app.CheckBox_102.Value==1
                text_current=app.EditField_22.Value;
                if ~isempty(text_current) && ~strcmp(text_current,'NaN')
                    page=str2double(text_current);
                    mA=app.AMTMSOEditField_152.Value;
                    [mA_M_H_all,mA_M_H_mean] = Hreflex_RC_prep(app,page,subpage,mA, ...
                        ind_m,ind_h,mA_M_H_all,mA_M_H_mean);
                end
            end
            if app.CheckBox_103.Value==1
                text_current=app.EditField_23.Value;
                if ~isempty(text_current) && ~strcmp(text_current,'NaN')
                    page=str2double(text_current);
                    mA=app.AMTMSOEditField_153.Value;
                    [mA_M_H_all,mA_M_H_mean] = Hreflex_RC_prep(app,page,subpage,mA, ...
                        ind_m,ind_h,mA_M_H_all,mA_M_H_mean);
                end
            end
            if app.CheckBox_104.Value==1
                text_current=app.EditField_24.Value;
                if ~isempty(text_current) && ~strcmp(text_current,'NaN')
                    page=str2double(text_current);
                    mA=app.AMTMSOEditField_154.Value;
                    [mA_M_H_all,mA_M_H_mean] = Hreflex_RC_prep(app,page,subpage,mA, ...
                        ind_m,ind_h,mA_M_H_all,mA_M_H_mean);
                end
            end
            if app.CheckBox_105.Value==1
                text_current=app.EditField_25.Value;
                if ~isempty(text_current) && ~strcmp(text_current,'NaN')
                    page=str2double(text_current);
                    mA=app.AMTMSOEditField_155.Value;
                    [mA_M_H_all,mA_M_H_mean] = Hreflex_RC_prep(app,page,subpage,mA, ...
                        ind_m,ind_h,mA_M_H_all,mA_M_H_mean);
                end
            end
            if app.CheckBox_106.Value==1
                text_current=app.EditField_26.Value;
                if ~isempty(text_current) && ~strcmp(text_current,'NaN')
                    page=str2double(text_current);
                    mA=app.AMTMSOEditField_156.Value;
                    [mA_M_H_all,mA_M_H_mean] = Hreflex_RC_prep(app,page,subpage,mA, ...
                        ind_m,ind_h,mA_M_H_all,mA_M_H_mean);
                end
            end
            if app.CheckBox_107.Value==1
                text_current=app.EditField_27.Value;
                if ~isempty(text_current) && ~strcmp(text_current,'NaN')
                    page=str2double(text_current);
                    mA=app.AMTMSOEditField_157.Value;
                    [mA_M_H_all,mA_M_H_mean] = Hreflex_RC_prep(app,page,subpage,mA, ...
                        ind_m,ind_h,mA_M_H_all,mA_M_H_mean);
                end
            end
            if app.CheckBox_108.Value==1
                text_current=app.EditField_28.Value;
                if ~isempty(text_current) && ~strcmp(text_current,'NaN')
                    page=str2double(text_current);
                    mA=app.AMTMSOEditField_158.Value;
                    [mA_M_H_all,mA_M_H_mean] = Hreflex_RC_prep(app,page,subpage,mA, ...
                        ind_m,ind_h,mA_M_H_all,mA_M_H_mean);
                end
            end
            if app.CheckBox_109.Value==1
                text_current=app.EditField_29.Value;
                if ~isempty(text_current) && ~strcmp(text_current,'NaN')
                    page=str2double(text_current);
                    mA=app.AMTMSOEditField_159.Value;
                    [mA_M_H_all,mA_M_H_mean] = Hreflex_RC_prep(app,page,subpage,mA, ...
                        ind_m,ind_h,mA_M_H_all,mA_M_H_mean);
                end
            end
            if app.CheckBox_110.Value==1
                text_current=app.EditField_30.Value;
                if ~isempty(text_current) && ~strcmp(text_current,'NaN')
                    page=str2double(text_current);
                    mA=app.AMTMSOEditField_160.Value;
                    [mA_M_H_all,mA_M_H_mean] = Hreflex_RC_prep(app,page,subpage,mA, ...
                        ind_m,ind_h,mA_M_H_all,mA_M_H_mean);
                end
            end
            if app.CheckBox_111.Value==1
                text_current=app.EditField_31.Value;
                if ~isempty(text_current) && ~strcmp(text_current,'NaN')
                    page=str2double(text_current);
                    mA=app.AMTMSOEditField_161.Value;
                    [mA_M_H_all,mA_M_H_mean] = Hreflex_RC_prep(app,page,subpage,mA, ...
                        ind_m,ind_h,mA_M_H_all,mA_M_H_mean);
                end
            end
            if app.CheckBox_112.Value==1
                text_current=app.EditField_32.Value;
                if ~isempty(text_current) && ~strcmp(text_current,'NaN')
                    page=str2double(text_current);
                    mA=app.AMTMSOEditField_162.Value;
                    [mA_M_H_all,mA_M_H_mean] = Hreflex_RC_prep(app,page,subpage,mA, ...
                        ind_m,ind_h,mA_M_H_all,mA_M_H_mean);
                end
            end
            if app.CheckBox_113.Value==1
                text_current=app.EditField_33.Value;
                if ~isempty(text_current) && ~strcmp(text_current,'NaN')
                    page=str2double(text_current);
                    mA=app.AMTMSOEditField_163.Value;
                    [mA_M_H_all,mA_M_H_mean] = Hreflex_RC_prep(app,page,subpage,mA, ...
                        ind_m,ind_h,mA_M_H_all,mA_M_H_mean);
                end
            end
            if app.CheckBox_114.Value==1
                text_current=app.EditField_34.Value;
                if ~isempty(text_current) && ~strcmp(text_current,'NaN')
                    page=str2double(text_current);
                    mA=app.AMTMSOEditField_164.Value;
                    [mA_M_H_all,mA_M_H_mean] = Hreflex_RC_prep(app,page,subpage,mA, ...
                        ind_m,ind_h,mA_M_H_all,mA_M_H_mean);
                end
            end
            if app.CheckBox_115.Value==1
                text_current=app.EditField_35.Value;
                if ~isempty(text_current) && ~strcmp(text_current,'NaN')
                    page=str2double(text_current);
                    mA=app.AMTMSOEditField_165.Value;
                    [mA_M_H_all,mA_M_H_mean] = Hreflex_RC_prep(app,page,subpage,mA, ...
                        ind_m,ind_h,mA_M_H_all,mA_M_H_mean);
                end
            end
            if app.CheckBox_116.Value==1
                text_current=app.EditField_36.Value;
                if ~isempty(text_current) && ~strcmp(text_current,'NaN')
                    page=str2double(text_current);
                    mA=app.AMTMSOEditField_166.Value;
                    [mA_M_H_all,mA_M_H_mean] = Hreflex_RC_prep(app,page,subpage,mA, ...
                        ind_m,ind_h,mA_M_H_all,mA_M_H_mean);
                end
            end
            if app.CheckBox_117.Value==1
                text_current=app.EditField_37.Value;
                if ~isempty(text_current) && ~strcmp(text_current,'NaN')
                    page=str2double(text_current);
                    mA=app.AMTMSOEditField_167.Value;
                    [mA_M_H_all,mA_M_H_mean] = Hreflex_RC_prep(app,page,subpage,mA, ...
                        ind_m,ind_h,mA_M_H_all,mA_M_H_mean);
                end
            end
            if app.CheckBox_118.Value==1
                text_current=app.EditField_38.Value;
                if ~isempty(text_current) && ~strcmp(text_current,'NaN')
                    page=str2double(text_current);
                    mA=app.AMTMSOEditField_168.Value;
                    [mA_M_H_all,mA_M_H_mean] = Hreflex_RC_prep(app,page,subpage,mA, ...
                        ind_m,ind_h,mA_M_H_all,mA_M_H_mean);
                end
            end
            if app.CheckBox_119.Value==1
                text_current=app.EditField_39.Value;
                if ~isempty(text_current) && ~strcmp(text_current,'NaN')
                    page=str2double(text_current);
                    mA=app.AMTMSOEditField_169.Value;
                    [mA_M_H_all,mA_M_H_mean] = Hreflex_RC_prep(app,page,subpage,mA, ...
                        ind_m,ind_h,mA_M_H_all,mA_M_H_mean);
                end
            end
            if app.CheckBox_120.Value==1
                text_current=app.EditField_40.Value;
                if ~isempty(text_current) && ~strcmp(text_current,'NaN')
                    page=str2double(text_current);
                    mA=app.AMTMSOEditField_170.Value;
                    [mA_M_H_all,mA_M_H_mean] = Hreflex_RC_prep(app,page,subpage,mA, ...
                        ind_m,ind_h,mA_M_H_all,mA_M_H_mean);
                end
            end
            if app.CheckBox_121.Value==1
                text_current=app.EditField_41.Value;
                if ~isempty(text_current) && ~strcmp(text_current,'NaN')
                    page=str2double(text_current);
                    mA=app.AMTMSOEditField_171.Value;
                    [mA_M_H_all,mA_M_H_mean] = Hreflex_RC_prep(app,page,subpage,mA, ...
                        ind_m,ind_h,mA_M_H_all,mA_M_H_mean);
                end
            end
            if app.CheckBox_122.Value==1
                text_current=app.EditField_42.Value;
                if ~isempty(text_current) && ~strcmp(text_current,'NaN')
                    page=str2double(text_current);
                    mA=app.AMTMSOEditField_172.Value;
                    [mA_M_H_all,mA_M_H_mean] = Hreflex_RC_prep(app,page,subpage,mA, ...
                        ind_m,ind_h,mA_M_H_all,mA_M_H_mean);
                end
            end
            if app.CheckBox_123.Value==1
                text_current=app.EditField_43.Value;
                if ~isempty(text_current) && ~strcmp(text_current,'NaN')
                    page=str2double(text_current);
                    mA=app.AMTMSOEditField_173.Value;
                    [mA_M_H_all,mA_M_H_mean] = Hreflex_RC_prep(app,page,subpage,mA, ...
                        ind_m,ind_h,mA_M_H_all,mA_M_H_mean);
                end
            end
            if app.CheckBox_124.Value==1
                text_current=app.EditField_44.Value;
                if ~isempty(text_current) && ~strcmp(text_current,'NaN')
                    page=str2double(text_current);
                    mA=app.AMTMSOEditField_174.Value;
                    [mA_M_H_all,mA_M_H_mean] = Hreflex_RC_prep(app,page,subpage,mA, ...
                        ind_m,ind_h,mA_M_H_all,mA_M_H_mean);
                end
            end
            if app.CheckBox_125.Value==1
                text_current=app.EditField_45.Value;
                if ~isempty(text_current) && ~strcmp(text_current,'NaN')
                    page=str2double(text_current);
                    mA=app.AMTMSOEditField_175.Value;
                    [mA_M_H_all,mA_M_H_mean] = Hreflex_RC_prep(app,page,subpage,mA, ...
                        ind_m,ind_h,mA_M_H_all,mA_M_H_mean);
                end
            end
            if app.CheckBox_126.Value==1
                text_current=app.EditField_46.Value;
                if ~isempty(text_current) && ~strcmp(text_current,'NaN')
                    page=str2double(text_current);
                    mA=app.AMTMSOEditField_176.Value;
                    [mA_M_H_all,mA_M_H_mean] = Hreflex_RC_prep(app,page,subpage,mA, ...
                        ind_m,ind_h,mA_M_H_all,mA_M_H_mean);
                end
            end
            if app.CheckBox_127.Value==1
                text_current=app.EditField_47.Value;
                if ~isempty(text_current) && ~strcmp(text_current,'NaN')
                    page=str2double(text_current);
                    mA=app.AMTMSOEditField_177.Value;
                    [mA_M_H_all,mA_M_H_mean] = Hreflex_RC_prep(app,page,subpage,mA, ...
                        ind_m,ind_h,mA_M_H_all,mA_M_H_mean);
                end
            end
            if app.CheckBox_128.Value==1
                text_current=app.EditField_48.Value;
                if ~isempty(text_current) && ~strcmp(text_current,'NaN')
                    page=str2double(text_current);
                    mA=app.AMTMSOEditField_178.Value;
                    [mA_M_H_all,mA_M_H_mean] = Hreflex_RC_prep(app,page,subpage,mA, ...
                        ind_m,ind_h,mA_M_H_all,mA_M_H_mean);
                end
            end
            if app.CheckBox_129.Value==1
                text_current=app.EditField_49.Value;
                if ~isempty(text_current) && ~strcmp(text_current,'NaN')
                    page=str2double(text_current);
                    mA=app.AMTMSOEditField_179.Value;
                    [mA_M_H_all,mA_M_H_mean] = Hreflex_RC_prep(app,page,subpage,mA, ...
                        ind_m,ind_h,mA_M_H_all,mA_M_H_mean);
                end
            end
            if app.CheckBox_130.Value==1
                text_current=app.EditField_50.Value;
                if ~isempty(text_current) && ~strcmp(text_current,'NaN')
                    page=str2double(text_current);
                    mA=app.AMTMSOEditField_180.Value;
                    [mA_M_H_all,mA_M_H_mean] = Hreflex_RC_prep(app,page,subpage,mA, ...
                        ind_m,ind_h,mA_M_H_all,mA_M_H_mean);
                end
            end
            if app.CheckBox_131.Value==1
                text_current=app.EditField_51.Value;
                if ~isempty(text_current) && ~strcmp(text_current,'NaN')
                    page=str2double(text_current);
                    mA=app.AMTMSOEditField_181.Value;
                    [mA_M_H_all,mA_M_H_mean] = Hreflex_RC_prep(app,page,subpage,mA, ...
                        ind_m,ind_h,mA_M_H_all,mA_M_H_mean);
                end
            end
            if app.CheckBox_132.Value==1
                text_current=app.EditField_52.Value;
                if ~isempty(text_current) && ~strcmp(text_current,'NaN')
                    page=str2double(text_current);
                    mA=app.AMTMSOEditField_182.Value;
                    [mA_M_H_all,mA_M_H_mean] = Hreflex_RC_prep(app,page,subpage,mA, ...
                        ind_m,ind_h,mA_M_H_all,mA_M_H_mean);
                end
            end
            if app.CheckBox_133.Value==1
                text_current=app.EditField_53.Value;
                if ~isempty(text_current) && ~strcmp(text_current,'NaN')
                    page=str2double(text_current);
                    mA=app.AMTMSOEditField_183.Value;
                    [mA_M_H_all,mA_M_H_mean] = Hreflex_RC_prep(app,page,subpage,mA, ...
                        ind_m,ind_h,mA_M_H_all,mA_M_H_mean);
                end
            end
            if app.CheckBox_134.Value==1
                text_current=app.EditField_54.Value;
                if ~isempty(text_current) && ~strcmp(text_current,'NaN')
                    page=str2double(text_current);
                    mA=app.AMTMSOEditField_184.Value;
                    [mA_M_H_all,mA_M_H_mean] = Hreflex_RC_prep(app,page,subpage,mA, ...
                        ind_m,ind_h,mA_M_H_all,mA_M_H_mean);
                end
            end
            if app.CheckBox_135.Value==1
                text_current=app.EditField_55.Value;
                if ~isempty(text_current) && ~strcmp(text_current,'NaN')
                    page=str2double(text_current);
                    mA=app.AMTMSOEditField_185.Value;
                    [mA_M_H_all,mA_M_H_mean] = Hreflex_RC_prep(app,page,subpage,mA, ...
                        ind_m,ind_h,mA_M_H_all,mA_M_H_mean);
                end
            end
            if app.CheckBox_136.Value==1
                text_current=app.EditField_56.Value;
                if ~isempty(text_current) && ~strcmp(text_current,'NaN')
                    page=str2double(text_current);
                    mA=app.AMTMSOEditField_186.Value;
                    [mA_M_H_all,mA_M_H_mean] = Hreflex_RC_prep(app,page,subpage,mA, ...
                        ind_m,ind_h,mA_M_H_all,mA_M_H_mean);
                end
            end
            if app.CheckBox_137.Value==1
                text_current=app.EditField_57.Value;
                if ~isempty(text_current) && ~strcmp(text_current,'NaN')
                    page=str2double(text_current);
                    mA=app.AMTMSOEditField_187.Value;
                    [mA_M_H_all,mA_M_H_mean] = Hreflex_RC_prep(app,page,subpage,mA, ...
                        ind_m,ind_h,mA_M_H_all,mA_M_H_mean);
                end
            end
            if app.CheckBox_138.Value==1
                text_current=app.EditField_58.Value;
                if ~isempty(text_current) && ~strcmp(text_current,'NaN')
                    page=str2double(text_current);
                    mA=app.AMTMSOEditField_188.Value;
                    [mA_M_H_all,mA_M_H_mean] = Hreflex_RC_prep(app,page,subpage,mA, ...
                        ind_m,ind_h,mA_M_H_all,mA_M_H_mean);
                end
            end


            if ~isempty(mA_M_H_mean)
                [~,idx] = sort(mA_M_H_mean(:,1)); % sort just the MSO column
                sorted_mA_M_H_mean = mA_M_H_mean(idx,:);   % sort the whole matrix using the sort indices
                sorted_mA_M_H_all= mA_M_H_all(idx,:);
                
                cla(app.UIAxes);
                plot(app.UIAxes,sorted_mA_M_H_all(:,1),sorted_mA_M_H_all(:,2),'.k','MarkerSize',5);
                hold(app.UIAxes,'on');
                plot(app.UIAxes,sorted_mA_M_H_all(:,1),sorted_mA_M_H_all(:,3),'.b','MarkerSize',5);
                hold(app.UIAxes,'on');
                
                plot(app.UIAxes,sorted_mA_M_H_mean(:,1),sorted_mA_M_H_mean(:,2),'.-k','MarkerSize',20,"linewidth",1);
                hold(app.UIAxes,'on');
                plot(app.UIAxes,sorted_mA_M_H_mean(:,1),sorted_mA_M_H_mean(:,3),'.-b','MarkerSize',20,"linewidth",1);
                hold(app.UIAxes,'on');
                
                set(app.UIAxes,'xtick',sorted_mA_M_H_mean(:,1));
                ylabel(app.UIAxes,'mV');
                xlabel(app.UIAxes,'mA');
                title(app.UIAxes,'Hreflex H-M Recruitment Curve','fontsize',30)
            else
                cla(app.UIAxes);
                text(app.UIAxes,0.5,0.5,'Please select at least one mA option(s)','Units', 'Normalized', 'fontsize', 20,...
                    'HorizontalAlignment', 'Center');
            end
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.NeuroPhysTestingUIFigure = uifigure('Visible', 'off');
            app.NeuroPhysTestingUIFigure.Name = 'NeuroPhys Testing';
            app.UIAxes = uiaxes(app.NeuroPhysTestingUIFigure);
            zlabel(app.UIAxes, 'Z')
            app.TabGroup = uitabgroup(app.NeuroPhysTestingUIFigure);
            app.MEPDeterminationTab = uitab(app.TabGroup);
            app.MEPDeterminationTab.Title = 'MEP Determination';
            app.Switch = uiswitch(app.MEPDeterminationTab, 'slider');
            app.Switch.Items = {'AMT   ', 'RMT'};
            app.Switch.Value = 'AMT   ';
            app.Lamp = uilamp(app.MEPDeterminationTab);
            app.STARTButton = uibutton(app.MEPDeterminationTab, 'push');
            app.STARTButton.ButtonPushedFcn = createCallbackFcn(app, @STARTButtonPushed, true);
            app.STARTButton.Text = 'START';
            app.ScopeViewtimerangemsEditField_2Label = uilabel(app.MEPDeterminationTab);
            app.ScopeViewtimerangemsEditField_2Label.Text = 'ScopeView time range (ms)';
            app.ScopeViewtimerangemsEditField_2 = uieditfield(app.MEPDeterminationTab, 'numeric');
            app.ScopeViewtimerangemsEditField_3 = uieditfield(app.MEPDeterminationTab, 'numeric');
            app.ScopeViewtimerangemsEditField_4 = uieditfield(app.MEPDeterminationTab, 'numeric');
            app.BaselinetimerangemsEditFieldLabel = uilabel(app.MEPDeterminationTab);
            app.BaselinetimerangemsEditFieldLabel.Text = 'Baseline time range (ms)';
            app.BaselinetimerangemsEditField = uieditfield(app.MEPDeterminationTab, 'numeric');
            app.ScopeViewtimerangemsEditField_6 = uieditfield(app.MEPDeterminationTab, 'numeric');
            app.MEPtimerangemsEditFieldLabel = uilabel(app.MEPDeterminationTab);
            app.MEPtimerangemsEditFieldLabel.Text = 'MEP time range (ms)';
            app.MEPtimerangemsEditField = uieditfield(app.MEPDeterminationTab, 'numeric');
            app.ChannelNoEditFieldLabel = uilabel(app.MEPDeterminationTab);
            app.ChannelNoEditFieldLabel.Text = 'Channel No.';
            app.ChannelNoEditField = uieditfield(app.MEPDeterminationTab, 'numeric');
            app.StartingPageNoEditFieldLabel = uilabel(app.MEPDeterminationTab);
            app.StartingPageNoEditFieldLabel.Text = 'Starting Page No.';
            app.StartingPageNoEditField = uieditfield(app.MEPDeterminationTab, 'numeric');
            app.fromLabel = uilabel(app.MEPDeterminationTab);
            app.fromLabel.Text = 'from';
            app.toLabel = uilabel(app.MEPDeterminationTab);
            app.toLabel.Text = 'to';
            app.UnlinkLabChartButton = uibutton(app.MEPDeterminationTab, 'push');
            app.UnlinkLabChartButton.ButtonPushedFcn = createCallbackFcn(app, @UnlinkLabChartButtonPushed, true);
            app.UnlinkLabChartButton.Text = 'Unlink LabChart';
            app.TMSRecruitmentCurveTab = uitab(app.TabGroup);
            app.TMSRecruitmentCurveTab.Title = 'TMS Recruitment Curve';
            app.PlotRCButton = uibutton(app.TMSRecruitmentCurveTab, 'state');
            app.PlotRCButton.ValueChangedFcn = createCallbackFcn(app, @PlotRCButtonValueChanged, true);
            app.PlotRCButton.Text = 'Plot RC';
            app.AMTMSOEditFieldLabel = uilabel(app.TMSRecruitmentCurveTab);
            app.AMTMSOEditFieldLabel.Text = 'AMT(%MSO)';
            app.AMTMSOEditField = uieditfield(app.TMSRecruitmentCurveTab, 'numeric');
            app.AMTMSOEditField.ValueChangedFcn = createCallbackFcn(app, @AMTMSOEditFieldValueChanged, true);
            app.CheckBox = uicheckbox(app.TMSRecruitmentCurveTab);
            app.CheckBox.ValueChangedFcn = createCallbackFcn(app, @CheckBoxValueChanged, true);
            app.CheckBox.Text = '120';
            app.CheckBox_2 = uicheckbox(app.TMSRecruitmentCurveTab);
            app.CheckBox_2.ValueChangedFcn = createCallbackFcn(app, @CheckBox_2ValueChanged, true);
            app.CheckBox_2.Text = '110';
            app.CheckBox_3 = uicheckbox(app.TMSRecruitmentCurveTab);
            app.CheckBox_3.ValueChangedFcn = createCallbackFcn(app, @CheckBox_3ValueChanged, true);
            app.CheckBox_3.Text = '150';
            app.CheckBox_4 = uicheckbox(app.TMSRecruitmentCurveTab);
            app.CheckBox_4.ValueChangedFcn = createCallbackFcn(app, @CheckBox_4ValueChanged, true);
            app.CheckBox_4.Text = '130';
            app.CheckBox_5 = uicheckbox(app.TMSRecruitmentCurveTab);
            app.CheckBox_5.ValueChangedFcn = createCallbackFcn(app, @CheckBox_5ValueChanged, true);
            app.CheckBox_5.Text = '140';
            app.CheckBox_6 = uicheckbox(app.TMSRecruitmentCurveTab);
            app.CheckBox_6.ValueChangedFcn = createCallbackFcn(app, @CheckBox_6ValueChanged, true);
            app.CheckBox_6.Text = '90';
            app.CheckBox_7 = uicheckbox(app.TMSRecruitmentCurveTab);
            app.CheckBox_7.ValueChangedFcn = createCallbackFcn(app, @CheckBox_7ValueChanged, true);
            app.CheckBox_7.Text = '100';
            app.CheckBox_8 = uicheckbox(app.TMSRecruitmentCurveTab);
            app.CheckBox_8.ValueChangedFcn = createCallbackFcn(app, @CheckBox_8ValueChanged, true);
            app.CheckBox_8.Text = '160';
            app.AMTLabel_2 = uilabel(app.TMSRecruitmentCurveTab);
            app.AMTLabel_2.Text = '%AMT';
            app.MSOLabel = uilabel(app.TMSRecruitmentCurveTab);
            app.MSOLabel.Text = '%MSO';
            app.CheckBox_9 = uicheckbox(app.TMSRecruitmentCurveTab);
            app.CheckBox_9.ValueChangedFcn = createCallbackFcn(app, @CheckBox_9ValueChanged, true);
            app.CheckBox_9.Text = '170';
            app.CheckBox_10 = uicheckbox(app.TMSRecruitmentCurveTab);
            app.CheckBox_10.ValueChangedFcn = createCallbackFcn(app, @CheckBox_10ValueChanged, true);
            app.CheckBox_10.Text = '180';
            app.CheckBox_11 = uicheckbox(app.TMSRecruitmentCurveTab);
            app.CheckBox_11.ValueChangedFcn = createCallbackFcn(app, @CheckBox_11ValueChanged, true);
            app.CheckBox_11.Text = '190';
            app.CheckBox_12 = uicheckbox(app.TMSRecruitmentCurveTab);
            app.CheckBox_12.ValueChangedFcn = createCallbackFcn(app, @CheckBox_12ValueChanged, true);
            app.CheckBox_12.Text = '200';
            app.CheckBox_13 = uicheckbox(app.TMSRecruitmentCurveTab);
            app.CheckBox_13.ValueChangedFcn = createCallbackFcn(app, @CheckBox_13ValueChanged, true);
            app.CheckBox_13.Text = '210';
            app.CheckBox_14 = uicheckbox(app.TMSRecruitmentCurveTab);
            app.CheckBox_14.ValueChangedFcn = createCallbackFcn(app, @CheckBox_14ValueChanged, true);
            app.CheckBox_14.Text = '220';
            app.CheckBox_15 = uicheckbox(app.TMSRecruitmentCurveTab);
            app.CheckBox_15.ValueChangedFcn = createCallbackFcn(app, @CheckBox_15ValueChanged, true);
            app.CheckBox_15.Text = '80';
            app.CheckBox_16 = uicheckbox(app.TMSRecruitmentCurveTab);
            app.CheckBox_16.ValueChangedFcn = createCallbackFcn(app, @CheckBox_16ValueChanged, true);
            app.CheckBox_16.Text = '70';
            app.PageLabel = uilabel(app.TMSRecruitmentCurveTab);
            app.PageLabel.Text = 'Page#';
            app.AMTLabel_3 = uilabel(app.TMSRecruitmentCurveTab);
            app.AMTLabel_3.Text = '%AMT';
            app.MSOLabel_2 = uilabel(app.TMSRecruitmentCurveTab);
            app.MSOLabel_2.Text = '%MSO';
            app.PageLabel_2 = uilabel(app.TMSRecruitmentCurveTab);
            app.PageLabel_2.Text = 'Page#';
            app.AMTMSOEditField_2 = uieditfield(app.TMSRecruitmentCurveTab, 'numeric');
            app.AMTMSOEditField_3 = uieditfield(app.TMSRecruitmentCurveTab, 'numeric');
            app.AMTMSOEditField_4 = uieditfield(app.TMSRecruitmentCurveTab, 'numeric');
            app.AMTMSOEditField_5 = uieditfield(app.TMSRecruitmentCurveTab, 'numeric');
            app.AMTMSOEditField_6 = uieditfield(app.TMSRecruitmentCurveTab, 'numeric');
            app.AMTMSOEditField_7 = uieditfield(app.TMSRecruitmentCurveTab, 'numeric');
            app.AMTMSOEditField_8 = uieditfield(app.TMSRecruitmentCurveTab, 'numeric');
            app.AMTMSOEditField_9 = uieditfield(app.TMSRecruitmentCurveTab, 'numeric');
            app.AMTMSOEditField_18 = uieditfield(app.TMSRecruitmentCurveTab, 'numeric');
            app.AMTMSOEditField_19 = uieditfield(app.TMSRecruitmentCurveTab, 'numeric');
            app.AMTMSOEditField_20 = uieditfield(app.TMSRecruitmentCurveTab, 'numeric');
            app.AMTMSOEditField_21 = uieditfield(app.TMSRecruitmentCurveTab, 'numeric');
            app.AMTMSOEditField_22 = uieditfield(app.TMSRecruitmentCurveTab, 'numeric');
            app.AMTMSOEditField_23 = uieditfield(app.TMSRecruitmentCurveTab, 'numeric');
            app.AMTMSOEditField_24 = uieditfield(app.TMSRecruitmentCurveTab, 'numeric');
            app.AMTMSOEditField_25 = uieditfield(app.TMSRecruitmentCurveTab, 'numeric');
            app.EditField_2 = uieditfield(app.TMSRecruitmentCurveTab, 'text');
            app.EditField_2.ValueChangedFcn = createCallbackFcn(app, @EditField_2ValueChanged, true);
            app.EditField_3 = uieditfield(app.TMSRecruitmentCurveTab, 'text');
            app.EditField_3.ValueChangedFcn = createCallbackFcn(app, @EditField_3ValueChanged, true);
            app.EditField_4 = uieditfield(app.TMSRecruitmentCurveTab, 'text');
            app.EditField_4.ValueChangedFcn = createCallbackFcn(app, @EditField_4ValueChanged, true);
            app.EditField_5 = uieditfield(app.TMSRecruitmentCurveTab, 'text');
            app.EditField_5.ValueChangedFcn = createCallbackFcn(app, @EditField_5ValueChanged, true);
            app.EditField_6 = uieditfield(app.TMSRecruitmentCurveTab, 'text');
            app.EditField_6.ValueChangedFcn = createCallbackFcn(app, @EditField_6ValueChanged, true);
            app.EditField_7 = uieditfield(app.TMSRecruitmentCurveTab, 'text');
            app.EditField_7.ValueChangedFcn = createCallbackFcn(app, @EditField_7ValueChanged, true);
            app.EditField_8 = uieditfield(app.TMSRecruitmentCurveTab, 'text');
            app.EditField_8.ValueChangedFcn = createCallbackFcn(app, @EditField_8ValueChanged, true);
            app.EditField_1 = uieditfield(app.TMSRecruitmentCurveTab, 'text');
            app.EditField_1.ValueChangedFcn = createCallbackFcn(app, @EditField_1ValueChanged, true);
            app.EditField_9 = uieditfield(app.TMSRecruitmentCurveTab, 'text');
            app.EditField_9.ValueChangedFcn = createCallbackFcn(app, @EditField_9ValueChanged, true);
            app.EditField_10 = uieditfield(app.TMSRecruitmentCurveTab, 'text');
            app.EditField_10.ValueChangedFcn = createCallbackFcn(app, @EditField_10ValueChanged, true);
            app.EditField_11 = uieditfield(app.TMSRecruitmentCurveTab, 'text');
            app.EditField_11.ValueChangedFcn = createCallbackFcn(app, @EditField_11ValueChanged, true);
            app.EditField_12 = uieditfield(app.TMSRecruitmentCurveTab, 'text');
            app.EditField_12.ValueChangedFcn = createCallbackFcn(app, @EditField_12ValueChanged, true);
            app.EditField_13 = uieditfield(app.TMSRecruitmentCurveTab, 'text');
            app.EditField_13.ValueChangedFcn = createCallbackFcn(app, @EditField_13ValueChanged, true);
            app.EditField_14 = uieditfield(app.TMSRecruitmentCurveTab, 'text');
            app.EditField_14.ValueChangedFcn = createCallbackFcn(app, @EditField_14ValueChanged, true);
            app.EditField_15 = uieditfield(app.TMSRecruitmentCurveTab, 'text');
            app.EditField_15.ValueChangedFcn = createCallbackFcn(app, @EditField_15ValueChanged, true);
            app.EditField_16 = uieditfield(app.TMSRecruitmentCurveTab, 'text');
            app.EditField_16.ValueChangedFcn = createCallbackFcn(app, @EditField_16ValueChanged, true);
            app.CheckBox_17 = uicheckbox(app.TMSRecruitmentCurveTab);
            app.CheckBox_17.ValueChangedFcn = createCallbackFcn(app, @CheckBox_17ValueChanged, true);
            app.CheckBox_17.Text = '*120';
            app.AMTMSOEditField_26 = uieditfield(app.TMSRecruitmentCurveTab, 'numeric');
            app.EditField_17 = uieditfield(app.TMSRecruitmentCurveTab, 'text');
            app.EditField_17.ValueChangedFcn = createCallbackFcn(app, @EditField_17ValueChanged, true);
            app.CheckBox_18 = uicheckbox(app.TMSRecruitmentCurveTab);
            app.CheckBox_18.ValueChangedFcn = createCallbackFcn(app, @CheckBox_18ValueChanged, true);
            app.CheckBox_18.Text = '*90';
            app.AMTMSOEditField_27 = uieditfield(app.TMSRecruitmentCurveTab, 'numeric');
            app.EditField_18 = uieditfield(app.TMSRecruitmentCurveTab, 'text');
            app.EditField_18.ValueChangedFcn = createCallbackFcn(app, @EditField_18ValueChanged, true);
            app.AMTMSOEditField_28 = uieditfield(app.TMSRecruitmentCurveTab, 'numeric');
            app.AMTMSOEditField_28.ValueChangedFcn = createCallbackFcn(app, @AMTMSOEditField_28ValueChanged, true);
            app.NewAMTCheckBox = uicheckbox(app.TMSRecruitmentCurveTab);
            app.NewAMTCheckBox.ValueChangedFcn = createCallbackFcn(app, @NewAMTCheckBoxValueChanged, true);
            app.NewAMTCheckBox.Text = 'New AMT';
            app.HReflexRecruitmentCurveTab = uitab(app.TabGroup);
            app.HReflexRecruitmentCurveTab.Title = 'H-Reflex Recruitment Curve';
            app.SubpagebyEditFieldLabel = uilabel(app.HReflexRecruitmentCurveTab);
            app.SubpagebyEditFieldLabel.Text = 'Subpage by';
            app.SubpagebyEditField = uieditfield(app.HReflexRecruitmentCurveTab, 'numeric');
            app.PlotHMRCButton = uibutton(app.HReflexRecruitmentCurveTab, 'state');
            app.PlotHMRCButton.ValueChangedFcn = createCallbackFcn(app, @PlotHMRCButtonValueChanged, true);
            app.PlotHMRCButton.Text = 'Plot H-M RC';
            app.MmstoEditField = uieditfield(app.HReflexRecruitmentCurveTab, 'numeric');
            app.HmstoEditField = uieditfield(app.HReflexRecruitmentCurveTab, 'numeric');
            app.MmsEditFieldLabel = uilabel(app.HReflexRecruitmentCurveTab);
            app.MmsEditFieldLabel.Text = 'M (ms)';
            app.MmsEditField = uieditfield(app.HReflexRecruitmentCurveTab, 'numeric');
            app.HmsEditFieldLabel = uilabel(app.HReflexRecruitmentCurveTab);
            app.HmsEditFieldLabel.Text = 'H (ms)';
            app.HmsEditField = uieditfield(app.HReflexRecruitmentCurveTab, 'numeric');
            app.fromLabel_2 = uilabel(app.HReflexRecruitmentCurveTab);
            app.fromLabel_2.Text = 'from';
            app.toLabel_2 = uilabel(app.HReflexRecruitmentCurveTab);
            app.toLabel_2.Text = 'to';
            app.mALabel_9 = uilabel(app.HReflexRecruitmentCurveTab);
            app.mALabel_9.Text = 'mA';
            app.PageLabel_11 = uilabel(app.HReflexRecruitmentCurveTab);
            app.PageLabel_11.Text = 'Page#';
            app.mALabel_10 = uilabel(app.HReflexRecruitmentCurveTab);
            app.mALabel_10.Text = 'mA';
            app.PageLabel_12 = uilabel(app.HReflexRecruitmentCurveTab);
            app.PageLabel_12.Text = 'Page#';
            app.mALabel_11 = uilabel(app.HReflexRecruitmentCurveTab);
            app.mALabel_11.Text = 'mA';
            app.PageLabel_13 = uilabel(app.HReflexRecruitmentCurveTab);
            app.PageLabel_13.Text = 'Page#';
            app.mALabel_12 = uilabel(app.HReflexRecruitmentCurveTab);
            app.mALabel_12.Text = 'mA';
            app.PageLabel_14 = uilabel(app.HReflexRecruitmentCurveTab);
            app.PageLabel_14.Text = 'Page#';
            app.CheckBox_99 = uicheckbox(app.HReflexRecruitmentCurveTab);
            app.CheckBox_99.ValueChangedFcn = createCallbackFcn(app, @CheckBox_99ValueChanged, true);
            app.CheckBox_99.Text = '';
            app.CheckBox_100 = uicheckbox(app.HReflexRecruitmentCurveTab);
            app.CheckBox_100.ValueChangedFcn = createCallbackFcn(app, @CheckBox_100ValueChanged, true);
            app.CheckBox_100.Text = '';
            app.CheckBox_101 = uicheckbox(app.HReflexRecruitmentCurveTab);
            app.CheckBox_101.ValueChangedFcn = createCallbackFcn(app, @CheckBox_101ValueChanged, true);
            app.CheckBox_101.Text = '';
            app.CheckBox_102 = uicheckbox(app.HReflexRecruitmentCurveTab);
            app.CheckBox_102.ValueChangedFcn = createCallbackFcn(app, @CheckBox_102ValueChanged, true);
            app.CheckBox_102.Text = '';
            app.CheckBox_103 = uicheckbox(app.HReflexRecruitmentCurveTab);
            app.CheckBox_103.ValueChangedFcn = createCallbackFcn(app, @CheckBox_103ValueChanged, true);
            app.CheckBox_103.Text = '';
            app.CheckBox_104 = uicheckbox(app.HReflexRecruitmentCurveTab);
            app.CheckBox_104.ValueChangedFcn = createCallbackFcn(app, @CheckBox_104ValueChanged, true);
            app.CheckBox_104.Text = '';
            app.CheckBox_105 = uicheckbox(app.HReflexRecruitmentCurveTab);
            app.CheckBox_105.ValueChangedFcn = createCallbackFcn(app, @CheckBox_105ValueChanged, true);
            app.CheckBox_105.Text = '';
            app.CheckBox_106 = uicheckbox(app.HReflexRecruitmentCurveTab);
            app.CheckBox_106.ValueChangedFcn = createCallbackFcn(app, @CheckBox_106ValueChanged, true);
            app.CheckBox_106.Text = '';
            app.CheckBox_107 = uicheckbox(app.HReflexRecruitmentCurveTab);
            app.CheckBox_107.ValueChangedFcn = createCallbackFcn(app, @CheckBox_107ValueChanged, true);
            app.CheckBox_107.Text = '';
            app.CheckBox_108 = uicheckbox(app.HReflexRecruitmentCurveTab);
            app.CheckBox_108.ValueChangedFcn = createCallbackFcn(app, @CheckBox_108ValueChanged, true);
            app.CheckBox_108.Text = '';
            app.CheckBox_109 = uicheckbox(app.HReflexRecruitmentCurveTab);
            app.CheckBox_109.ValueChangedFcn = createCallbackFcn(app, @CheckBox_109ValueChanged, true);
            app.CheckBox_109.Text = '';
            app.CheckBox_110 = uicheckbox(app.HReflexRecruitmentCurveTab);
            app.CheckBox_110.ValueChangedFcn = createCallbackFcn(app, @CheckBox_110ValueChanged, true);
            app.CheckBox_110.Text = '';
            app.CheckBox_111 = uicheckbox(app.HReflexRecruitmentCurveTab);
            app.CheckBox_111.ValueChangedFcn = createCallbackFcn(app, @CheckBox_111ValueChanged, true);
            app.CheckBox_111.Text = '';
            app.CheckBox_112 = uicheckbox(app.HReflexRecruitmentCurveTab);
            app.CheckBox_112.ValueChangedFcn = createCallbackFcn(app, @CheckBox_112ValueChanged, true);
            app.CheckBox_112.Text = '';
            app.CheckBox_113 = uicheckbox(app.HReflexRecruitmentCurveTab);
            app.CheckBox_113.ValueChangedFcn = createCallbackFcn(app, @CheckBox_113ValueChanged, true);
            app.CheckBox_113.Text = '';
            app.CheckBox_114 = uicheckbox(app.HReflexRecruitmentCurveTab);
            app.CheckBox_114.ValueChangedFcn = createCallbackFcn(app, @CheckBox_114ValueChanged, true);
            app.CheckBox_114.Text = '';
            app.CheckBox_115 = uicheckbox(app.HReflexRecruitmentCurveTab);
            app.CheckBox_115.ValueChangedFcn = createCallbackFcn(app, @CheckBox_115ValueChanged, true);
            app.CheckBox_115.Text = '';
            app.CheckBox_116 = uicheckbox(app.HReflexRecruitmentCurveTab);
            app.CheckBox_116.ValueChangedFcn = createCallbackFcn(app, @CheckBox_116ValueChanged, true);
            app.CheckBox_116.Text = '';
            app.CheckBox_117 = uicheckbox(app.HReflexRecruitmentCurveTab);
            app.CheckBox_117.ValueChangedFcn = createCallbackFcn(app, @CheckBox_117ValueChanged, true);
            app.CheckBox_117.Text = '';
            app.CheckBox_118 = uicheckbox(app.HReflexRecruitmentCurveTab);
            app.CheckBox_118.ValueChangedFcn = createCallbackFcn(app, @CheckBox_118ValueChanged, true);
            app.CheckBox_118.Text = '';
            app.CheckBox_119 = uicheckbox(app.HReflexRecruitmentCurveTab);
            app.CheckBox_119.ValueChangedFcn = createCallbackFcn(app, @CheckBox_119ValueChanged, true);
            app.CheckBox_119.Text = '';
            app.CheckBox_120 = uicheckbox(app.HReflexRecruitmentCurveTab);
            app.CheckBox_120.ValueChangedFcn = createCallbackFcn(app, @CheckBox_120ValueChanged, true);
            app.CheckBox_120.Text = '';
            app.CheckBox_121 = uicheckbox(app.HReflexRecruitmentCurveTab);
            app.CheckBox_121.ValueChangedFcn = createCallbackFcn(app, @CheckBox_121ValueChanged, true);
            app.CheckBox_121.Text = '';
            app.CheckBox_122 = uicheckbox(app.HReflexRecruitmentCurveTab);
            app.CheckBox_122.ValueChangedFcn = createCallbackFcn(app, @CheckBox_122ValueChanged, true);
            app.CheckBox_122.Text = '';
            app.CheckBox_123 = uicheckbox(app.HReflexRecruitmentCurveTab);
            app.CheckBox_123.ValueChangedFcn = createCallbackFcn(app, @CheckBox_123ValueChanged, true);
            app.CheckBox_123.Text = '';
            app.CheckBox_124 = uicheckbox(app.HReflexRecruitmentCurveTab);
            app.CheckBox_124.ValueChangedFcn = createCallbackFcn(app, @CheckBox_124ValueChanged, true);
            app.CheckBox_124.Text = '';
            app.CheckBox_125 = uicheckbox(app.HReflexRecruitmentCurveTab);
            app.CheckBox_125.ValueChangedFcn = createCallbackFcn(app, @CheckBox_125ValueChanged, true);
            app.CheckBox_125.Text = '';
            app.CheckBox_126 = uicheckbox(app.HReflexRecruitmentCurveTab);
            app.CheckBox_126.ValueChangedFcn = createCallbackFcn(app, @CheckBox_126ValueChanged, true);
            app.CheckBox_126.Text = '';
            app.CheckBox_127 = uicheckbox(app.HReflexRecruitmentCurveTab);
            app.CheckBox_127.ValueChangedFcn = createCallbackFcn(app, @CheckBox_127ValueChanged, true);
            app.CheckBox_127.Text = '';
            app.CheckBox_128 = uicheckbox(app.HReflexRecruitmentCurveTab);
            app.CheckBox_128.ValueChangedFcn = createCallbackFcn(app, @CheckBox_128ValueChanged, true);
            app.CheckBox_128.Text = '';
            app.CheckBox_129 = uicheckbox(app.HReflexRecruitmentCurveTab);
            app.CheckBox_129.ValueChangedFcn = createCallbackFcn(app, @CheckBox_129ValueChanged, true);
            app.CheckBox_129.Text = '';
            app.CheckBox_130 = uicheckbox(app.HReflexRecruitmentCurveTab);
            app.CheckBox_130.ValueChangedFcn = createCallbackFcn(app, @CheckBox_130ValueChanged, true);
            app.CheckBox_130.Text = '';
            app.CheckBox_131 = uicheckbox(app.HReflexRecruitmentCurveTab);
            app.CheckBox_131.ValueChangedFcn = createCallbackFcn(app, @CheckBox_131ValueChanged, true);
            app.CheckBox_131.Text = '';
            app.CheckBox_132 = uicheckbox(app.HReflexRecruitmentCurveTab);
            app.CheckBox_132.ValueChangedFcn = createCallbackFcn(app, @CheckBox_132ValueChanged, true);
            app.CheckBox_132.Text = '';
            app.CheckBox_133 = uicheckbox(app.HReflexRecruitmentCurveTab);
            app.CheckBox_133.ValueChangedFcn = createCallbackFcn(app, @CheckBox_133ValueChanged, true);
            app.CheckBox_133.Text = '';
            app.CheckBox_134 = uicheckbox(app.HReflexRecruitmentCurveTab);
            app.CheckBox_134.ValueChangedFcn = createCallbackFcn(app, @CheckBox_134ValueChanged, true);
            app.CheckBox_134.Text = '';
            app.CheckBox_135 = uicheckbox(app.HReflexRecruitmentCurveTab);
            app.CheckBox_135.ValueChangedFcn = createCallbackFcn(app, @CheckBox_135ValueChanged, true);
            app.CheckBox_135.Text = '';
            app.CheckBox_136 = uicheckbox(app.HReflexRecruitmentCurveTab);
            app.CheckBox_136.ValueChangedFcn = createCallbackFcn(app, @CheckBox_136ValueChanged, true);
            app.CheckBox_136.Text = '';
            app.CheckBox_137 = uicheckbox(app.HReflexRecruitmentCurveTab);
            app.CheckBox_137.ValueChangedFcn = createCallbackFcn(app, @CheckBox_137ValueChanged, true);
            app.CheckBox_137.Text = '';
            app.CheckBox_138 = uicheckbox(app.HReflexRecruitmentCurveTab);
            app.CheckBox_138.ValueChangedFcn = createCallbackFcn(app, @CheckBox_138ValueChanged, true);
            app.CheckBox_138.Text = '';
            app.AMTMSOEditField_149 = uieditfield(app.HReflexRecruitmentCurveTab, 'numeric');
            app.AMTMSOEditField_150 = uieditfield(app.HReflexRecruitmentCurveTab, 'numeric');
            app.AMTMSOEditField_151 = uieditfield(app.HReflexRecruitmentCurveTab, 'numeric');
            app.AMTMSOEditField_152 = uieditfield(app.HReflexRecruitmentCurveTab, 'numeric');
            app.AMTMSOEditField_153 = uieditfield(app.HReflexRecruitmentCurveTab, 'numeric');
            app.AMTMSOEditField_154 = uieditfield(app.HReflexRecruitmentCurveTab, 'numeric');
            app.AMTMSOEditField_155 = uieditfield(app.HReflexRecruitmentCurveTab, 'numeric');
            app.AMTMSOEditField_156 = uieditfield(app.HReflexRecruitmentCurveTab, 'numeric');
            app.AMTMSOEditField_157 = uieditfield(app.HReflexRecruitmentCurveTab, 'numeric');
            app.AMTMSOEditField_158 = uieditfield(app.HReflexRecruitmentCurveTab, 'numeric');
            app.AMTMSOEditField_159 = uieditfield(app.HReflexRecruitmentCurveTab, 'numeric');
            app.AMTMSOEditField_160 = uieditfield(app.HReflexRecruitmentCurveTab, 'numeric');
            app.AMTMSOEditField_161 = uieditfield(app.HReflexRecruitmentCurveTab, 'numeric');
            app.AMTMSOEditField_162 = uieditfield(app.HReflexRecruitmentCurveTab, 'numeric');
            app.AMTMSOEditField_163 = uieditfield(app.HReflexRecruitmentCurveTab, 'numeric');
            app.AMTMSOEditField_164 = uieditfield(app.HReflexRecruitmentCurveTab, 'numeric');
            app.AMTMSOEditField_165 = uieditfield(app.HReflexRecruitmentCurveTab, 'numeric');
            app.AMTMSOEditField_166 = uieditfield(app.HReflexRecruitmentCurveTab, 'numeric');
            app.AMTMSOEditField_167 = uieditfield(app.HReflexRecruitmentCurveTab, 'numeric');
            app.AMTMSOEditField_168 = uieditfield(app.HReflexRecruitmentCurveTab, 'numeric');
            app.AMTMSOEditField_169 = uieditfield(app.HReflexRecruitmentCurveTab, 'numeric');
            app.AMTMSOEditField_170 = uieditfield(app.HReflexRecruitmentCurveTab, 'numeric');
            app.AMTMSOEditField_171 = uieditfield(app.HReflexRecruitmentCurveTab, 'numeric');
            app.AMTMSOEditField_172 = uieditfield(app.HReflexRecruitmentCurveTab, 'numeric');
            app.AMTMSOEditField_173 = uieditfield(app.HReflexRecruitmentCurveTab, 'numeric');
            app.AMTMSOEditField_174 = uieditfield(app.HReflexRecruitmentCurveTab, 'numeric');
            app.AMTMSOEditField_175 = uieditfield(app.HReflexRecruitmentCurveTab, 'numeric');
            app.AMTMSOEditField_176 = uieditfield(app.HReflexRecruitmentCurveTab, 'numeric');
            app.AMTMSOEditField_177 = uieditfield(app.HReflexRecruitmentCurveTab, 'numeric');
            app.AMTMSOEditField_178 = uieditfield(app.HReflexRecruitmentCurveTab, 'numeric');
            app.AMTMSOEditField_179 = uieditfield(app.HReflexRecruitmentCurveTab, 'numeric');
            app.AMTMSOEditField_180 = uieditfield(app.HReflexRecruitmentCurveTab, 'numeric');
            app.AMTMSOEditField_181 = uieditfield(app.HReflexRecruitmentCurveTab, 'numeric');
            app.AMTMSOEditField_182 = uieditfield(app.HReflexRecruitmentCurveTab, 'numeric');
            app.AMTMSOEditField_183 = uieditfield(app.HReflexRecruitmentCurveTab, 'numeric');
            app.AMTMSOEditField_184 = uieditfield(app.HReflexRecruitmentCurveTab, 'numeric');
            app.AMTMSOEditField_185 = uieditfield(app.HReflexRecruitmentCurveTab, 'numeric');
            app.AMTMSOEditField_186 = uieditfield(app.HReflexRecruitmentCurveTab, 'numeric');
            app.AMTMSOEditField_187 = uieditfield(app.HReflexRecruitmentCurveTab, 'numeric');
            app.AMTMSOEditField_188 = uieditfield(app.HReflexRecruitmentCurveTab, 'numeric');
            app.EditField_19 = uieditfield(app.HReflexRecruitmentCurveTab, 'text');
            app.EditField_20 = uieditfield(app.HReflexRecruitmentCurveTab, 'text');
            app.EditField_21 = uieditfield(app.HReflexRecruitmentCurveTab, 'text');
            app.EditField_22 = uieditfield(app.HReflexRecruitmentCurveTab, 'text');
            app.EditField_23 = uieditfield(app.HReflexRecruitmentCurveTab, 'text');
            app.EditField_24 = uieditfield(app.HReflexRecruitmentCurveTab, 'text');
            app.EditField_25 = uieditfield(app.HReflexRecruitmentCurveTab, 'text');
            app.EditField_26 = uieditfield(app.HReflexRecruitmentCurveTab, 'text');
            app.EditField_27 = uieditfield(app.HReflexRecruitmentCurveTab, 'text');
            app.EditField_28 = uieditfield(app.HReflexRecruitmentCurveTab, 'text');
            app.EditField_29 = uieditfield(app.HReflexRecruitmentCurveTab, 'text');
            app.EditField_30 = uieditfield(app.HReflexRecruitmentCurveTab, 'text');
            app.EditField_31 = uieditfield(app.HReflexRecruitmentCurveTab, 'text');
            app.EditField_32 = uieditfield(app.HReflexRecruitmentCurveTab, 'text');
            app.EditField_33 = uieditfield(app.HReflexRecruitmentCurveTab, 'text');
            app.EditField_34 = uieditfield(app.HReflexRecruitmentCurveTab, 'text');
            app.EditField_35 = uieditfield(app.HReflexRecruitmentCurveTab, 'text');
            app.EditField_36 = uieditfield(app.HReflexRecruitmentCurveTab, 'text');
            app.EditField_37 = uieditfield(app.HReflexRecruitmentCurveTab, 'text');
            app.EditField_38 = uieditfield(app.HReflexRecruitmentCurveTab, 'text');
            app.EditField_39 = uieditfield(app.HReflexRecruitmentCurveTab, 'text');
            app.EditField_40 = uieditfield(app.HReflexRecruitmentCurveTab, 'text');
            app.EditField_41 = uieditfield(app.HReflexRecruitmentCurveTab, 'text');
            app.EditField_42 = uieditfield(app.HReflexRecruitmentCurveTab, 'text');
            app.EditField_43 = uieditfield(app.HReflexRecruitmentCurveTab, 'text');
            app.EditField_44 = uieditfield(app.HReflexRecruitmentCurveTab, 'text');
            app.EditField_45 = uieditfield(app.HReflexRecruitmentCurveTab, 'text');
            app.EditField_46 = uieditfield(app.HReflexRecruitmentCurveTab, 'text');
            app.EditField_47 = uieditfield(app.HReflexRecruitmentCurveTab, 'text');
            app.EditField_48 = uieditfield(app.HReflexRecruitmentCurveTab, 'text');
            app.EditField_49 = uieditfield(app.HReflexRecruitmentCurveTab, 'text');
            app.EditField_50 = uieditfield(app.HReflexRecruitmentCurveTab, 'text');
            app.EditField_51 = uieditfield(app.HReflexRecruitmentCurveTab, 'text');
            app.EditField_52 = uieditfield(app.HReflexRecruitmentCurveTab, 'text');
            app.EditField_53 = uieditfield(app.HReflexRecruitmentCurveTab, 'text');
            app.EditField_54 = uieditfield(app.HReflexRecruitmentCurveTab, 'text');
            app.EditField_55 = uieditfield(app.HReflexRecruitmentCurveTab, 'text');
            app.EditField_56 = uieditfield(app.HReflexRecruitmentCurveTab, 'text');
            app.EditField_57 = uieditfield(app.HReflexRecruitmentCurveTab, 'text');
            app.EditField_58 = uieditfield(app.HReflexRecruitmentCurveTab, 'text');
            app.CheckBox_139 = uicheckbox(app.HReflexRecruitmentCurveTab);
            app.CheckBox_139.ValueChangedFcn = createCallbackFcn(app, @CheckBox_139ValueChanged, true);
            app.CheckBox_139.Text = '';
            app.CheckBox_140 = uicheckbox(app.HReflexRecruitmentCurveTab);
            app.CheckBox_140.ValueChangedFcn = createCallbackFcn(app, @CheckBox_140ValueChanged, true);
            app.CheckBox_140.Text = '';
            app.CheckBox_141 = uicheckbox(app.HReflexRecruitmentCurveTab);
            app.CheckBox_141.ValueChangedFcn = createCallbackFcn(app, @CheckBox_141ValueChanged, true);
            app.CheckBox_141.Text = '';
            app.CheckBox_142 = uicheckbox(app.HReflexRecruitmentCurveTab);
            app.CheckBox_142.ValueChangedFcn = createCallbackFcn(app, @CheckBox_142ValueChanged, true);
            app.CheckBox_142.Text = '';
            % Show the figure after all components are created
            app.NeuroPhysTestingUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Jia_Test

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.NeuroPhysTestingUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.NeuroPhysTestingUIFigure)
        end
    end
end